classdef interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TFGUIFigure                     matlab.ui.Figure
        PrincipalPanel                  matlab.ui.container.Panel
        STARTButton                     matlab.ui.control.Button
        ImagePrincipal                  matlab.ui.control.Image
        TrainingGoogleNetPanel          matlab.ui.container.Panel
        Atras_2                         matlab.ui.control.Button
        PanelBotones_2                  matlab.ui.container.Panel
        AjustesButton_2                 matlab.ui.control.Button
        RedimensionarButton_2           matlab.ui.control.Button
        PanelEmpty_2                    matlab.ui.container.Panel
        RedimensionarPanel_2            matlab.ui.container.Panel
        EXITOLabel_2                    matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        STARTButton_3                   matlab.ui.control.Button
        CARGARIMAGENButton_2            matlab.ui.control.Button
        ajustarPanel_2                  matlab.ui.container.Panel
        RESETButton_2                   matlab.ui.control.Button
        PLAYButton_2                    matlab.ui.control.Button
        numValidationImagesEditField_2  matlab.ui.control.NumericEditField
        numValidationImagesEditField_2Label  matlab.ui.control.Label
        numTrainImagesEditField_2       matlab.ui.control.NumericEditField
        numTrainImagesEditField_2Label  matlab.ui.control.Label
        IterationsEditField_2           matlab.ui.control.NumericEditField
        IterationsEditField_2Label      matlab.ui.control.Label
        ValidationPatienceEditField_2   matlab.ui.control.NumericEditField
        ValidationPatienceEditField_2Label  matlab.ui.control.Label
        ValidationFrequencyEditField_2  matlab.ui.control.NumericEditField
        ValidationFrequencyEditField_2Label  matlab.ui.control.Label
        InitialLearnRateEditField_2     matlab.ui.control.NumericEditField
        InitialLearnRateEditField_2Label  matlab.ui.control.Label
        MaxEpochsEditField_2            matlab.ui.control.NumericEditField
        MaxEpochsEditField_2Label       matlab.ui.control.Label
        MiniBatchSizeEditField_2        matlab.ui.control.NumericEditField
        MiniBatchSizeEditField_2Label   matlab.ui.control.Label
        TrainingAlexNetPanel            matlab.ui.container.Panel
        Atras                           matlab.ui.control.Button
        PanelBotones                    matlab.ui.container.Panel
        AjustesButton                   matlab.ui.control.Button
        RedimensionarButton             matlab.ui.control.Button
        PanelEmpty                      matlab.ui.container.Panel
        RedimensionarPanel              matlab.ui.container.Panel
        EXITOLabel                      matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        STARTButton_2                   matlab.ui.control.Button
        CARGARIMAGENButton              matlab.ui.control.Button
        ajustarPanel                    matlab.ui.container.Panel
        RESETButton                     matlab.ui.control.Button
        PLAYButton                      matlab.ui.control.Button
        numValidationImagesEditField    matlab.ui.control.NumericEditField
        numValidationImagesEditFieldLabel  matlab.ui.control.Label
        numTrainImagesEditField         matlab.ui.control.NumericEditField
        numTrainImagesEditFieldLabel    matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        ValidationPatienceEditField     matlab.ui.control.NumericEditField
        ValidationPatienceEditFieldLabel  matlab.ui.control.Label
        ValidationFrequencyEditField    matlab.ui.control.NumericEditField
        ValidationFrequencyEditFieldLabel  matlab.ui.control.Label
        InitialLearnRateEditField       matlab.ui.control.NumericEditField
        InitialLearnRateEditFieldLabel  matlab.ui.control.Label
        MaxEpochsEditField              matlab.ui.control.NumericEditField
        MaxEpochsEditFieldLabel         matlab.ui.control.Label
        MiniBatchSizeEditField          matlab.ui.control.NumericEditField
        MiniBatchSizeEditFieldLabel     matlab.ui.control.Label
        SELECCINDEMODELODEREDPanel      matlab.ui.container.Panel
        atrasButton                     matlab.ui.control.Button
        GOOGLENETButton_2               matlab.ui.control.Button
        ALEXNETButton_2                 matlab.ui.control.Button
        ALEXNETPanel                    matlab.ui.container.Panel
        atrasButton_2                   matlab.ui.control.Button
        CLASIFICATIONButton             matlab.ui.control.Button
        TRAININGButton                  matlab.ui.control.Button
        GOOGLENETPanel                  matlab.ui.container.Panel
        atrasButton_3                   matlab.ui.control.Button
        CLASIFICATIONButton_2           matlab.ui.control.Button
        TRAININGButton_2                matlab.ui.control.Button
        ClassificationGoogleNetPanel    matlab.ui.container.Panel
        Atras_4                         matlab.ui.control.Button
        RESULTADOSPanel_2               matlab.ui.container.Panel
        Panel_2                         matlab.ui.container.Panel
        pauseButton_2                   matlab.ui.control.Button
        stopButton_2                    matlab.ui.control.Button
        playButton_2                    matlab.ui.control.Button
        cargarVideoButton_2             matlab.ui.control.Button
        Image2_2                        matlab.ui.control.Image
        ClassificationAlexNetPanel      matlab.ui.container.Panel
        Atras_3                         matlab.ui.control.Button
        RESULTADOSPanel                 matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        pauseButton                     matlab.ui.control.Button
        stopButton                      matlab.ui.control.Button
        playButton                      matlab.ui.control.Button
        cargarVideoButton               matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function cargarValoresTrainingAlex(app, ~)
            tempX = load('ajustesTrainingAlex.mat');
            app.MiniBatchSizeEditField.Value = tempX.MiniBatchSizeX;
            app.MaxEpochsEditField.Value = tempX.MaxEpochsX;
            app.InitialLearnRateEditField.Value = tempX.InitialLearnRateX;
            app.ValidationFrequencyEditField.Value = tempX.ValidationFrequencyX;
            app.ValidationPatienceEditField.Value = tempX.ValidationPatienceX;
            app.numTrainImagesEditField.Value = tempX.numTrainImages;
            app.numValidationImagesEditField.Value = tempX.numValidationImages;
            app.IterationsEditField.Value = tempX.Iteraciones;
            
            clear tempX;
        end
        
        function cargarValoresTrainingGoogle(app, ~)
            tempX = load('ajustesTrainingAlex.mat');
            app.MiniBatchSizeEditField_2.Value = tempX.MiniBatchSizeX;
            app.MaxEpochsEditField_2.Value = tempX.MaxEpochsX;
            app.InitialLearnRateEditField_2.Value = tempX.InitialLearnRateX;
            app.ValidationFrequencyEditField_2.Value = tempX.ValidationFrequencyX;
            app.ValidationPatienceEditField_2.Value = tempX.ValidationPatienceX;
            app.numTrainImagesEditField_2.Value = tempX.numTrainImages;
            app.numValidationImagesEditField_2.Value = tempX.numValidationImages;
            app.IterationsEditField_2.Value = tempX.Iteraciones;
            
            clear tempX;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.PrincipalPanel.Visible = 'off';
            app.SELECCINDEMODELODEREDPanel.Visible = 'on';
        end

        % Button pushed function: atrasButton
        function atrasButtonPushed(app, event)
            app.SELECCINDEMODELODEREDPanel.Visible="off";
            app.PrincipalPanel.Visible="on";
        end

        % Button pushed function: GOOGLENETButton_2
        function GOOGLENETButtonPushed(app, event)
            app.SELECCINDEMODELODEREDPanel.Visible="off";
            app.GOOGLENETPanel.Visible="on";
        end

        % Button pushed function: ALEXNETButton_2
        function ALEXNETButtonPushed(app, event)
            app.SELECCINDEMODELODEREDPanel.Visible="off";
            app.ALEXNETPanel.Visible="on";
        end

        % Button pushed function: atrasButton_2
        function atrasButton_2Pushed(app, event)
            app.ALEXNETPanel.Visible="off";
            app.SELECCINDEMODELODEREDPanel.Visible="on";
        end

        % Button pushed function: atrasButton_3
        function atrasButton_3Pushed(app, event)
            app.GOOGLENETPanel.Visible="off";
            app.SELECCINDEMODELODEREDPanel.Visible="on";
        end

        % Button pushed function: RedimensionarButton
        function RedimensionarButtonPushed(app, event)
            app.PanelEmpty.Title = 'Redimensionar';
            app.RedimensionarPanel.Visible="on";
            app.Image.ImageSource = "interrogacion.jpg";
            app.ajustarPanel.Visible="off";
            app.EXITOLabel.Visible="off";
            app.STARTButton_2.Visible="off";
        end

        % Button pushed function: AjustesButton
        function AjustesButtonPushed(app, event)
            app.PanelEmpty.Title = 'Ajustes';
            app.RedimensionarPanel.Visible="off";
            cargarValoresTrainingAlex(app);
            app.ajustarPanel.Visible="on";
        end

        % Button pushed function: CARGARIMAGENButton
        function CARGARIMAGENButtonPushed(app, event)
            ant=app.Image.ImageSource;
            app.RedimensionarPanel.Visible="on";
            app.EXITOLabel.Visible="off";
            app.STARTButton_2.Visible="off";
            warning('off','all');
            [aa, bb]=uigetfile({'*.png';'*.jpg'}, 'Cargar imagen');
            NombreImg = strcat(bb,aa);
           if(NombreImg~="")
                app.STARTButton_2.Visible = 'on';
                app.Image.ImageSource = NombreImg;
            else
                app.Image.ImageSource = ant;
           end
           warning('on','all');
            
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            imagen = imread(app.Image.ImageSource);
            imagen = imresize(imagen, [224 224]);
            imwrite(imagen, app.Image.ImageSource);
            app.EXITOLabel.Visible="on";
          
        end

        % Button pushed function: Atras
        function AtrasButtonPushed(app, event)
            app.TrainingAlexNetPanel.Visible="off";
            app.ALEXNETPanel.Visible= "on";
        end

        % Button pushed function: TRAININGButton
        function TRAININGButtonPushed(app, event)
            app.ALEXNETPanel.Visible="off";
            app.PanelEmpty.Title="";
            app.ajustarPanel.Visible="off";
            app.RedimensionarPanel.Visible="off";
            app.TrainingAlexNetPanel.Visible="on";
        end

        % Button pushed function: TRAININGButton_2
        function TRAININGButton_2Pushed(app, event)
            app.GOOGLENETPanel.Visible="off";
            app.PanelEmpty_2.Title="";
            app.ajustarPanel_2.Visible="off";
            app.RedimensionarPanel_2.Visible="off";
            app.TrainingGoogleNetPanel.Visible="on";
        end

        % Button pushed function: Atras_2
        function Atras_2ButtonPushed(app, event)
            app.TrainingGoogleNetPanel.Visible="off";
            app.GOOGLENETPanel.Visible="on";
        end

        % Button pushed function: AjustesButton_2
        function AjustesButton_2Pushed(app, event)
            app.PanelEmpty_2.Title = 'Ajustes';
            cargarValoresTrainingGoogle(app);
            app.RedimensionarPanel_2.Visible="off";
            app.ajustarPanel_2.Visible="on";
        end

        % Button pushed function: RedimensionarButton_2
        function RedimensionarButton_2Pushed(app, event)
            app.PanelEmpty_2.Title = 'Redimensionar';
            app.RedimensionarPanel_2.Visible="on";
            app.Image_2.ImageSource = "interrogacion.jpg";
            app.ajustarPanel_2.Visible="off";
            app.EXITOLabel_2.Visible="off";
            app.STARTButton_3.Visible="off";
        end

        % Button pushed function: STARTButton_3
        function STARTButton_3Pushed(app, event)
            imagen = imread(app.Image_2.ImageSource);
            imagen = imresize(imagen, [256 256]);
            imwrite(imagen, app.Image_2.ImageSource);
            app.EXITOLabel_2.Visible="on";
        end

        % Button pushed function: CARGARIMAGENButton_2
        function CARGARIMAGENButton_2Pushed(app, event)
            ant=app.Image_2.ImageSource;
            app.RedimensionarPanel_2.Visible="on";
            app.EXITOLabel_2.Visible="off";
            app.STARTButton_3.Visible="off";
            warning('off','all');
            [aa, bb]=uigetfile({'*.png';'*.jpg'}, 'Cargar imagen');
            NombreImg = strcat(bb,aa);
            if(NombreImg~="")
                app.STARTButton_3.Visible = 'on';
                app.Image_2.ImageSource = NombreImg;
            else
                app.Image_2.ImageSource = ant;
            end
            warning('on','all');
            
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            cargarValoresTrainingAlex(app);
        end

        % Button pushed function: Atras_3
        function Atras_3ButtonPushed(app, event)
            app.ClassificationAlexNetPanel.Visible="off";
            app.ALEXNETPanel.Visible="on";
        end

        % Button pushed function: Atras_4
        function Atras_4ButtonPushed(app, event)
            app.ClassificationGoogleNetPanel.Visible="off";
            app.GOOGLENETPanel.Visible="on";
        end

        % Button pushed function: CLASIFICATIONButton
        function CLASIFICATIONButtonPushed(app, event)
            app.ALEXNETPanel.Visible="off";
            app.ClassificationAlexNetPanel.Visible="on";
        end

        % Button pushed function: CLASIFICATIONButton_2
        function CLASIFICATIONButton_2Pushed(app, event)
            app.GOOGLENETPanel.Visible="off";
            app.ClassificationGoogleNetPanel.Visible="on";
        end

        % Button pushed function: RESETButton_2
        function RESETButton_2Pushed(app, event)
            cargarValoresTrainingGoogle(app);
        end

        % Button pushed function: pauseButton
        function pauseButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TFGUIFigure = uifigure('Visible', 'off');
            app.TFGUIFigure.Name = 'TFG';
            app.TFGUIFigure.Icon = 'conexion.png';
            app.ClassificationAlexNetPanel = uipanel(app.TFGUIFigure);
            app.ClassificationAlexNetPanel.Title = 'CLASSIFICATION ALEXNET';
            app.ClassificationAlexNetPanel.Visible = 'off';
            app.Image2 = uiimage(app.ClassificationAlexNetPanel);
            app.Panel = uipanel(app.ClassificationAlexNetPanel);
            app.cargarVideoButton = uibutton(app.Panel, 'push');
            app.cargarVideoButton.Icon = 'boton-de-carga.png';
            app.cargarVideoButton.Text = '';
            app.playButton = uibutton(app.Panel, 'push');
            app.playButton.Icon = 'play.png';
            app.playButton.Text = '';
            app.stopButton = uibutton(app.Panel, 'push');
            app.stopButton.Icon = 'senal-de-stop.png';
            app.stopButton.Text = '';
            app.pauseButton = uibutton(app.Panel, 'push');
            app.pauseButton.ButtonPushedFcn = createCallbackFcn(app, @pauseButtonPushed, true);
            app.pauseButton.Icon = 'pausa.png';
            app.pauseButton.Text = '';
            app.RESULTADOSPanel = uipanel(app.ClassificationAlexNetPanel);
            app.RESULTADOSPanel.Title = 'RESULTADOS';
            app.Atras_3 = uibutton(app.ClassificationAlexNetPanel, 'push');
            app.Atras_3.ButtonPushedFcn = createCallbackFcn(app, @Atras_3ButtonPushed, true);
            app.Atras_3.Icon = 'atras.png';
            app.Atras_3.Text = '';
            app.ClassificationGoogleNetPanel = uipanel(app.TFGUIFigure);
            app.ClassificationGoogleNetPanel.Title = 'CLASSIFICATION GOOGLENET';
            app.ClassificationGoogleNetPanel.Visible = 'off';
            app.Image2_2 = uiimage(app.ClassificationGoogleNetPanel);
            app.Panel_2 = uipanel(app.ClassificationGoogleNetPanel);
            app.cargarVideoButton_2 = uibutton(app.Panel_2, 'push');
            app.cargarVideoButton_2.Icon = 'boton-de-carga.png';
            app.cargarVideoButton_2.Text = '';
            app.playButton_2 = uibutton(app.Panel_2, 'push');
            app.playButton_2.Icon = 'play.png';
            app.playButton_2.Text = '';
            app.stopButton_2 = uibutton(app.Panel_2, 'push');
            app.stopButton_2.Icon = 'senal-de-stop.png';
            app.stopButton_2.Text = '';
            app.pauseButton_2 = uibutton(app.Panel_2, 'push');
            app.pauseButton_2.Icon = 'pausa.png';
            app.pauseButton_2.Text = '';
            app.RESULTADOSPanel_2 = uipanel(app.ClassificationGoogleNetPanel);
            app.RESULTADOSPanel_2.Title = 'RESULTADOS';
            app.Atras_4 = uibutton(app.ClassificationGoogleNetPanel, 'push');
            app.Atras_4.ButtonPushedFcn = createCallbackFcn(app, @Atras_4ButtonPushed, true);
            app.Atras_4.Icon = 'atras.png';
            app.Atras_4.Text = '';
            app.GOOGLENETPanel = uipanel(app.TFGUIFigure);
            app.GOOGLENETPanel.Title = 'GOOGLENET';
            app.GOOGLENETPanel.Visible = 'off';
            app.TRAININGButton_2 = uibutton(app.GOOGLENETPanel, 'push');
            app.TRAININGButton_2.ButtonPushedFcn = createCallbackFcn(app, @TRAININGButton_2Pushed, true);
            app.TRAININGButton_2.Icon = 'training.png';
            app.TRAININGButton_2.Text = 'TRAINING';
            app.CLASIFICATIONButton_2 = uibutton(app.GOOGLENETPanel, 'push');
            app.CLASIFICATIONButton_2.ButtonPushedFcn = createCallbackFcn(app, @CLASIFICATIONButton_2Pushed, true);
            app.CLASIFICATIONButton_2.Icon = 'clasification.png';
            app.CLASIFICATIONButton_2.Text = 'CLASIFICATION';
            app.atrasButton_3 = uibutton(app.GOOGLENETPanel, 'push');
            app.atrasButton_3.ButtonPushedFcn = createCallbackFcn(app, @atrasButton_3Pushed, true);
            app.atrasButton_3.Icon = 'atras.png';
            app.atrasButton_3.Text = '';
            app.ALEXNETPanel = uipanel(app.TFGUIFigure);
            app.ALEXNETPanel.Title = 'ALEXNET';
            app.ALEXNETPanel.Visible = 'off';
            app.TRAININGButton = uibutton(app.ALEXNETPanel, 'push');
            app.TRAININGButton.ButtonPushedFcn = createCallbackFcn(app, @TRAININGButtonPushed, true);
            app.TRAININGButton.Icon = 'training.png';
            app.TRAININGButton.Text = 'TRAINING';
            app.CLASIFICATIONButton = uibutton(app.ALEXNETPanel, 'push');
            app.CLASIFICATIONButton.ButtonPushedFcn = createCallbackFcn(app, @CLASIFICATIONButtonPushed, true);
            app.CLASIFICATIONButton.Icon = 'clasification.png';
            app.CLASIFICATIONButton.Text = 'CLASIFICATION';
            app.atrasButton_2 = uibutton(app.ALEXNETPanel, 'push');
            app.atrasButton_2.ButtonPushedFcn = createCallbackFcn(app, @atrasButton_2Pushed, true);
            app.atrasButton_2.Icon = 'atras.png';
            app.atrasButton_2.Text = '';
            app.SELECCINDEMODELODEREDPanel = uipanel(app.TFGUIFigure);
            app.SELECCINDEMODELODEREDPanel.Title = 'SELECCIÓN DE MODELO DE RED ';
            app.SELECCINDEMODELODEREDPanel.Visible = 'off';
            app.ALEXNETButton_2 = uibutton(app.SELECCINDEMODELODEREDPanel, 'push');
            app.ALEXNETButton_2.ButtonPushedFcn = createCallbackFcn(app, @ALEXNETButtonPushed, true);
            app.ALEXNETButton_2.Icon = 'A.png';
            app.ALEXNETButton_2.Text = 'ALEXNET';
            app.GOOGLENETButton_2 = uibutton(app.SELECCINDEMODELODEREDPanel, 'push');
            app.GOOGLENETButton_2.ButtonPushedFcn = createCallbackFcn(app, @GOOGLENETButtonPushed, true);
            app.GOOGLENETButton_2.Icon = 'G.png';
            app.GOOGLENETButton_2.Text = 'GOOGLENET';
            app.atrasButton = uibutton(app.SELECCINDEMODELODEREDPanel, 'push');
            app.atrasButton.ButtonPushedFcn = createCallbackFcn(app, @atrasButtonPushed, true);
            app.atrasButton.Icon = 'atras.png';
            app.atrasButton.Text = '';
            app.TrainingAlexNetPanel = uipanel(app.TFGUIFigure);
            app.TrainingAlexNetPanel.Title = 'TRAINING ALEXNET';
            app.TrainingAlexNetPanel.Visible = 'off';
            app.PanelEmpty = uipanel(app.TrainingAlexNetPanel);
            app.ajustarPanel = uipanel(app.PanelEmpty);
            app.ajustarPanel.Visible = 'off';
            app.MiniBatchSizeEditFieldLabel = uilabel(app.ajustarPanel);
            app.MiniBatchSizeEditFieldLabel.Text = 'MiniBatchSize';
            app.MiniBatchSizeEditField = uieditfield(app.ajustarPanel, 'numeric');
            app.MiniBatchSizeEditField.Limits = [0 300];
            app.MiniBatchSizeEditField.Value = 10;
            app.MaxEpochsEditFieldLabel = uilabel(app.ajustarPanel);
            app.MaxEpochsEditFieldLabel.Text = 'MaxEpochs';
            app.MaxEpochsEditField = uieditfield(app.ajustarPanel, 'numeric');
            app.InitialLearnRateEditFieldLabel = uilabel(app.ajustarPanel);
            app.InitialLearnRateEditFieldLabel.Text = 'InitialLearnRate';
            app.InitialLearnRateEditField = uieditfield(app.ajustarPanel, 'numeric');
            app.ValidationFrequencyEditFieldLabel = uilabel(app.ajustarPanel);
            app.ValidationFrequencyEditFieldLabel.Text = 'ValidationFrequency';
            app.ValidationFrequencyEditField = uieditfield(app.ajustarPanel, 'numeric');
            app.ValidationPatienceEditFieldLabel = uilabel(app.ajustarPanel);
            app.ValidationPatienceEditFieldLabel.Text = 'ValidationPatience';
            app.ValidationPatienceEditField = uieditfield(app.ajustarPanel, 'numeric');
            app.IterationsEditFieldLabel = uilabel(app.ajustarPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.ajustarPanel, 'numeric');
            app.numTrainImagesEditFieldLabel = uilabel(app.ajustarPanel);
            app.numTrainImagesEditFieldLabel.Text = 'numTrainImages';
            app.numTrainImagesEditField = uieditfield(app.ajustarPanel, 'numeric');
            app.numValidationImagesEditFieldLabel = uilabel(app.ajustarPanel);
            app.numValidationImagesEditFieldLabel.Text = 'numValidationImages';
            app.numValidationImagesEditField = uieditfield(app.ajustarPanel, 'numeric');
            app.PLAYButton = uibutton(app.ajustarPanel, 'push');
            app.PLAYButton.Icon = 'play.png';
            app.PLAYButton.Text = {'PLAY'; ''};
            app.RESETButton = uibutton(app.ajustarPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Icon = 'loading.png';
            app.RESETButton.Text = {'RESET'; ''};
            app.RedimensionarPanel = uipanel(app.PanelEmpty);
            app.RedimensionarPanel.Visible = 'off';
            app.CARGARIMAGENButton = uibutton(app.RedimensionarPanel, 'push');
            app.CARGARIMAGENButton.ButtonPushedFcn = createCallbackFcn(app, @CARGARIMAGENButtonPushed, true);
            app.CARGARIMAGENButton.Text = 'CARGAR IMAGEN';
            app.STARTButton_2 = uibutton(app.RedimensionarPanel, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Visible = 'off';
            app.STARTButton_2.Text = 'START';
            app.Image = uiimage(app.RedimensionarPanel);
            app.Image.ImageSource = 'interrogacion.jpg';
            app.EXITOLabel = uilabel(app.RedimensionarPanel);
            app.EXITOLabel.Visible = 'off';
            app.EXITOLabel.Text = '¡ÉXITO AL REDIMENSIONAR!';
            app.PanelBotones = uipanel(app.TrainingAlexNetPanel);
            app.RedimensionarButton = uibutton(app.PanelBotones, 'push');
            app.RedimensionarButton.ButtonPushedFcn = createCallbackFcn(app, @RedimensionarButtonPushed, true);
            app.RedimensionarButton.Icon = 'imagenes2.png';
            app.RedimensionarButton.Text = 'Redimensionar';
            app.AjustesButton = uibutton(app.PanelBotones, 'push');
            app.AjustesButton.ButtonPushedFcn = createCallbackFcn(app, @AjustesButtonPushed, true);
            app.AjustesButton.Icon = 'configuraciones.png';
            app.AjustesButton.Text = 'Ajustes';
            app.Atras = uibutton(app.TrainingAlexNetPanel, 'push');
            app.Atras.ButtonPushedFcn = createCallbackFcn(app, @AtrasButtonPushed, true);
            app.Atras.Icon = 'atras.png';
            app.Atras.Text = '';
            app.TrainingGoogleNetPanel = uipanel(app.TFGUIFigure);
            app.TrainingGoogleNetPanel.Title = 'TRAINING GOOGLENET';
            app.TrainingGoogleNetPanel.Visible = 'off';
            app.PanelEmpty_2 = uipanel(app.TrainingGoogleNetPanel);
            app.RedimensionarPanel_2 = uipanel(app.PanelEmpty_2);
            app.RedimensionarPanel_2.Visible = 'off';
            app.CARGARIMAGENButton_2 = uibutton(app.RedimensionarPanel_2, 'push');
            app.CARGARIMAGENButton_2.ButtonPushedFcn = createCallbackFcn(app, @CARGARIMAGENButton_2Pushed, true);
            app.CARGARIMAGENButton_2.Text = 'CARGAR IMAGEN';
            app.STARTButton_3 = uibutton(app.RedimensionarPanel_2, 'push');
            app.STARTButton_3.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_3Pushed, true);
            app.STARTButton_3.Visible = 'off';
            app.STARTButton_3.Text = 'START';
            app.Image_2 = uiimage(app.RedimensionarPanel_2);
            app.Image_2.ImageSource = 'interrogacion.jpg';
            app.EXITOLabel_2 = uilabel(app.RedimensionarPanel_2);
            app.EXITOLabel_2.Visible = 'off';
            app.EXITOLabel_2.Text = '¡ÉXITO AL REDIMENSIONAR!';
            app.ajustarPanel_2 = uipanel(app.PanelEmpty_2);
            app.ajustarPanel_2.Visible = 'off';
            app.MiniBatchSizeEditField_2Label = uilabel(app.ajustarPanel_2);
            app.MiniBatchSizeEditField_2Label.Text = 'MiniBatchSize';
            app.MiniBatchSizeEditField_2 = uieditfield(app.ajustarPanel_2, 'numeric');
            app.MaxEpochsEditField_2Label = uilabel(app.ajustarPanel_2);
            app.MaxEpochsEditField_2Label.Text = 'MaxEpochs';
            app.MaxEpochsEditField_2 = uieditfield(app.ajustarPanel_2, 'numeric');
            app.InitialLearnRateEditField_2Label = uilabel(app.ajustarPanel_2);
            app.InitialLearnRateEditField_2Label.Text = 'InitialLearnRate';
            app.InitialLearnRateEditField_2 = uieditfield(app.ajustarPanel_2, 'numeric');
            app.ValidationFrequencyEditField_2Label = uilabel(app.ajustarPanel_2);
            app.ValidationFrequencyEditField_2Label.Text = 'ValidationFrequency';
            app.ValidationFrequencyEditField_2 = uieditfield(app.ajustarPanel_2, 'numeric');
            app.ValidationPatienceEditField_2Label = uilabel(app.ajustarPanel_2);
            app.ValidationPatienceEditField_2Label.Text = 'ValidationPatience';
            app.ValidationPatienceEditField_2 = uieditfield(app.ajustarPanel_2, 'numeric');
            app.IterationsEditField_2Label = uilabel(app.ajustarPanel_2);
            app.IterationsEditField_2Label.Text = 'Iterations';
            app.IterationsEditField_2 = uieditfield(app.ajustarPanel_2, 'numeric');
            app.numTrainImagesEditField_2Label = uilabel(app.ajustarPanel_2);
            app.numTrainImagesEditField_2Label.Text = 'numTrainImages';
            app.numTrainImagesEditField_2 = uieditfield(app.ajustarPanel_2, 'numeric');
            app.numValidationImagesEditField_2Label = uilabel(app.ajustarPanel_2);
            app.numValidationImagesEditField_2Label.Text = 'numValidationImages';
            app.numValidationImagesEditField_2 = uieditfield(app.ajustarPanel_2, 'numeric');
            app.PLAYButton_2 = uibutton(app.ajustarPanel_2, 'push');
            app.PLAYButton_2.Icon = 'play.png';
            app.PLAYButton_2.Text = {'PLAY'; ''};
            app.RESETButton_2 = uibutton(app.ajustarPanel_2, 'push');
            app.RESETButton_2.ButtonPushedFcn = createCallbackFcn(app, @RESETButton_2Pushed, true);
            app.RESETButton_2.Icon = 'loading.png';
            app.RESETButton_2.Text = {'RESET'; ''};
            app.PanelBotones_2 = uipanel(app.TrainingGoogleNetPanel);
            app.RedimensionarButton_2 = uibutton(app.PanelBotones_2, 'push');
            app.RedimensionarButton_2.ButtonPushedFcn = createCallbackFcn(app, @RedimensionarButton_2Pushed, true);
            app.RedimensionarButton_2.Icon = 'imagenes2.png';
            app.RedimensionarButton_2.Text = 'Redimensionar';
            app.AjustesButton_2 = uibutton(app.PanelBotones_2, 'push');
            app.AjustesButton_2.ButtonPushedFcn = createCallbackFcn(app, @AjustesButton_2Pushed, true);
            app.AjustesButton_2.Icon = 'configuraciones.png';
            app.AjustesButton_2.Text = 'Ajustes';
            app.Atras_2 = uibutton(app.TrainingGoogleNetPanel, 'push');
            app.Atras_2.ButtonPushedFcn = createCallbackFcn(app, @Atras_2ButtonPushed, true);
            app.Atras_2.Icon = 'atras.png';
            app.Atras_2.Text = '';
            app.PrincipalPanel = uipanel(app.TFGUIFigure);
            app.PrincipalPanel.Title = 'RECONOCIMIENTO DE VEHÍCULOS';
            app.ImagePrincipal = uiimage(app.PrincipalPanel);
            app.ImagePrincipal.ImageSource = 'conexion.png';
            app.STARTButton = uibutton(app.PrincipalPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = {'START'; ''};
            % Show the figure after all components are created
            app.TFGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TFGUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TFGUIFigure)
        end
    end
end