classdef interfaz2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TrainingAlexNetPanel        matlab.ui.container.Panel
        Atras                       matlab.ui.control.Button
        PanelBotones                matlab.ui.container.Panel
        AjustesButton               matlab.ui.control.Button
        SubirImagenButton           matlab.ui.control.Button
        RedimensionarButton         matlab.ui.control.Button
        PanelEmpty                  matlab.ui.container.Panel
        RedimensionarPanel          matlab.ui.container.Panel
        Image                       matlab.ui.control.Image
        STARTButton_2               matlab.ui.control.Button
        CARGARIMAGENButton          matlab.ui.control.Button
        PrincipalPanel              matlab.ui.container.Panel
        STARTButton                 matlab.ui.control.Button
        ImagePrincipal              matlab.ui.control.Image
        SELECCINDEMODELODEREDPanel  matlab.ui.container.Panel
        atrasButton                 matlab.ui.control.Button
        GOOGLENETButton_2           matlab.ui.control.Button
        ALEXNETButton_2             matlab.ui.control.Button
        GOOGLENETPanel              matlab.ui.container.Panel
        atrasButton_3               matlab.ui.control.Button
        CLASIFICATIONButton_2       matlab.ui.control.Button
        TRAININGButton_2            matlab.ui.control.Button
        ALEXNETPanel                matlab.ui.container.Panel
        atrasButton_2               matlab.ui.control.Button
        CLASIFICATIONButton         matlab.ui.control.Button
        TRAININGButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.PrincipalPanel.Visible = 'off';
            app.SELECCINDEMODELODEREDPanel.Visible = 'on';
        end

        % Button pushed function: atrasButton
        function atrasButtonPushed(app, event)
            app.SELECCINDEMODELODEREDPanel.Visible="off";
            app.PrincipalPanel.Visible="on";
        end

        % Button pushed function: GOOGLENETButton_2
        function GOOGLENETButtonPushed(app, event)
            app.SELECCINDEMODELODEREDPanel.Visible="off";
            app.GOOGLENETPanel.Visible="on";
        end

        % Button pushed function: ALEXNETButton_2
        function ALEXNETButtonPushed(app, event)
            app.SELECCINDEMODELODEREDPanel.Visible="off";
            app.ALEXNETPanel.Visible="on";
        end

        % Button pushed function: atrasButton_2
        function atrasButton_2Pushed(app, event)
            app.ALEXNETPanel.Visible="off";
            app.SELECCINDEMODELODEREDPanel.Visible="on";
        end

        % Button pushed function: atrasButton_3
        function atrasButton_3Pushed(app, event)
            app.GOOGLENETPanel.Visible="off";
            app.SELECCINDEMODELODEREDPanel.Visible="on";
        end

        % Image clicked function: ImagePrincipal
        function ImagePrincipalClicked(app, event)
            
        end

        % Button pushed function: RedimensionarButton
        function RedimensionarButtonPushed(app, event)
            app.PanelEmpty.Title = 'Redimensionar';
        end

        % Button pushed function: SubirImagenButton
        function SubirImagenButtonPushed(app, event)
            app.PanelEmpty.Title = 'Subir Imagen';
        end

        % Button pushed function: AjustesButton
        function AjustesButtonPushed(app, event)
            app.PanelEmpty.Title = 'Ajustes';
        end

        % Button pushed function: CARGARIMAGENButton
        function CARGARIMAGENButtonPushed(app, event)
            [aa, bb]=uigetfile({'*.jpg';'*.png'}, 'Cargar imagen');
            NombreImg = strcat(bb,aa);
            app.Image.ImageSource = NombreImg;
            app.STARTButton_2.Visible = 'on';
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            imagen = imread(app.Image.ImageSource);
            imagen = imresize(imagen, [224 224]);
            imwrite(imagen, app.Image.ImageSource);
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ALEXNETPanel = uipanel(app.UIFigure);
            app.ALEXNETPanel.Title = 'ALEXNET';
            app.ALEXNETPanel.Visible = 'off';
            app.TRAININGButton = uibutton(app.ALEXNETPanel, 'push');
            app.TRAININGButton.Icon = 'training.png';
            app.TRAININGButton.Text = 'TRAINING';
            app.CLASIFICATIONButton = uibutton(app.ALEXNETPanel, 'push');
            app.CLASIFICATIONButton.Icon = 'clasification.png';
            app.CLASIFICATIONButton.Text = 'CLASIFICATION';
            app.atrasButton_2 = uibutton(app.ALEXNETPanel, 'push');
            app.atrasButton_2.ButtonPushedFcn = createCallbackFcn(app, @atrasButton_2Pushed, true);
            app.atrasButton_2.Icon = 'atras.png';
            app.atrasButton_2.Text = '';
            app.GOOGLENETPanel = uipanel(app.UIFigure);
            app.GOOGLENETPanel.Title = 'GOOGLENET';
            app.GOOGLENETPanel.Visible = 'off';
            app.TRAININGButton_2 = uibutton(app.GOOGLENETPanel, 'push');
            app.TRAININGButton_2.Icon = 'training.png';
            app.TRAININGButton_2.Text = 'TRAINING';
            app.CLASIFICATIONButton_2 = uibutton(app.GOOGLENETPanel, 'push');
            app.CLASIFICATIONButton_2.Icon = 'clasification.png';
            app.CLASIFICATIONButton_2.Text = 'CLASIFICATION';
            app.atrasButton_3 = uibutton(app.GOOGLENETPanel, 'push');
            app.atrasButton_3.ButtonPushedFcn = createCallbackFcn(app, @atrasButton_3Pushed, true);
            app.atrasButton_3.Icon = 'atras.png';
            app.atrasButton_3.Text = '';
            app.SELECCINDEMODELODEREDPanel = uipanel(app.UIFigure);
            app.SELECCINDEMODELODEREDPanel.Title = 'SELECCIÓN DE MODELO DE RED ';
            app.SELECCINDEMODELODEREDPanel.Visible = 'off';
            app.ALEXNETButton_2 = uibutton(app.SELECCINDEMODELODEREDPanel, 'push');
            app.ALEXNETButton_2.ButtonPushedFcn = createCallbackFcn(app, @ALEXNETButtonPushed, true);
            app.ALEXNETButton_2.Icon = 'A.png';
            app.ALEXNETButton_2.Text = 'ALEXNET';
            app.GOOGLENETButton_2 = uibutton(app.SELECCINDEMODELODEREDPanel, 'push');
            app.GOOGLENETButton_2.ButtonPushedFcn = createCallbackFcn(app, @GOOGLENETButtonPushed, true);
            app.GOOGLENETButton_2.Icon = 'G.png';
            app.GOOGLENETButton_2.Text = 'GOOGLENET';
            app.atrasButton = uibutton(app.SELECCINDEMODELODEREDPanel, 'push');
            app.atrasButton.ButtonPushedFcn = createCallbackFcn(app, @atrasButtonPushed, true);
            app.atrasButton.Icon = 'atras.png';
            app.atrasButton.Text = '';
            app.PrincipalPanel = uipanel(app.UIFigure);
            app.PrincipalPanel.Title = 'RECONOCIMIENTO DE VEHÍCULOS';
            app.ImagePrincipal = uiimage(app.PrincipalPanel);
            app.ImagePrincipal.ImageClickedFcn = createCallbackFcn(app, @ImagePrincipalClicked, true);
            app.ImagePrincipal.ImageSource = 'conexion.png';
            app.STARTButton = uibutton(app.PrincipalPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = {'START'; ''};
            app.TrainingAlexNetPanel = uipanel(app.UIFigure);
            app.TrainingAlexNetPanel.Title = 'TRAINING ALEXNET';
            app.PanelEmpty = uipanel(app.TrainingAlexNetPanel);
            app.RedimensionarPanel = uipanel(app.PanelEmpty);
            app.RedimensionarPanel.Visible = 'off';
            app.CARGARIMAGENButton = uibutton(app.RedimensionarPanel, 'push');
            app.CARGARIMAGENButton.ButtonPushedFcn = createCallbackFcn(app, @CARGARIMAGENButtonPushed, true);
            app.CARGARIMAGENButton.Text = 'CARGAR IMAGEN';
            app.STARTButton_2 = uibutton(app.RedimensionarPanel, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Visible = 'off';
            app.STARTButton_2.Text = 'START';
            app.Image = uiimage(app.RedimensionarPanel);
            app.Image.ImageSource = 'interrogacion.jpg';
            app.PanelBotones = uipanel(app.TrainingAlexNetPanel);
            app.RedimensionarButton = uibutton(app.PanelBotones, 'push');
            app.RedimensionarButton.ButtonPushedFcn = createCallbackFcn(app, @RedimensionarButtonPushed, true);
            app.RedimensionarButton.Icon = 'imagenes.png';
            app.RedimensionarButton.Text = 'Redimensionar';
            app.SubirImagenButton = uibutton(app.PanelBotones, 'push');
            app.SubirImagenButton.ButtonPushedFcn = createCallbackFcn(app, @SubirImagenButtonPushed, true);
            app.SubirImagenButton.Icon = 'imagenes2.png';
            app.SubirImagenButton.Text = 'Subir Imagen';
            app.AjustesButton = uibutton(app.PanelBotones, 'push');
            app.AjustesButton.ButtonPushedFcn = createCallbackFcn(app, @AjustesButtonPushed, true);
            app.AjustesButton.Icon = 'configuraciones.png';
            app.AjustesButton.Text = 'Ajustes';
            app.Atras = uibutton(app.TrainingAlexNetPanel, 'push');
            app.Atras.Icon = 'atras.png';
            app.Atras.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end