classdef interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TFGUIFigure                     matlab.ui.Figure
        PrincipalPanel                  matlab.ui.container.Panel
        STARTButton                     matlab.ui.control.Button
        ImagePrincipal                  matlab.ui.control.Image
        thingSpeakPanel                 matlab.ui.container.Panel
        CHECKTRAFFICButtonGroup         matlab.ui.container.ButtonGroup
        DATEPicker                      matlab.ui.control.DatePicker
        DATEDatePickerLabel             matlab.ui.control.Label
        CAMIONESFURGOSButton_2          matlab.ui.control.RadioButton
        MOTOSButton_2                   matlab.ui.control.RadioButton
        BUSESButton_2                   matlab.ui.control.RadioButton
        COCHESButton_2                  matlab.ui.control.RadioButton
        SENDTWEETButton                 matlab.ui.control.Button
        TWITTER_button                  matlab.ui.control.Button
        Atras_4                         matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        CHANNELButton                   matlab.ui.control.Button
        VEHICLESELECTIONButtonGroup     matlab.ui.container.ButtonGroup
        ALLButton                       matlab.ui.control.Button
        CAMIONESFURGOSButton            matlab.ui.control.RadioButton
        MOTOSButton                     matlab.ui.control.RadioButton
        BUSESButton                     matlab.ui.control.RadioButton
        COCHESButton                    matlab.ui.control.RadioButton
        ACCEPTButton                    matlab.ui.control.Button
        TrainingGoogleNetPanel          matlab.ui.container.Panel
        Atras_2                         matlab.ui.control.Button
        PanelBotones_2                  matlab.ui.container.Panel
        AjustesButton_2                 matlab.ui.control.Button
        RedimensionarButton_2           matlab.ui.control.Button
        PanelEmpty_2                    matlab.ui.container.Panel
        ajustarPanel_2                  matlab.ui.container.Panel
        WeightsCheckBox_2               matlab.ui.control.CheckBox
        NetworkAnalyzerCheckBox_2       matlab.ui.control.CheckBox
        ValidationImagesCheckBox_2      matlab.ui.control.CheckBox
        TrainingImagesCheckBox_2        matlab.ui.control.CheckBox
        RESETButton_2                   matlab.ui.control.Button
        PLAYButton_2                    matlab.ui.control.Button
        numValidationImagesEditField_2  matlab.ui.control.NumericEditField
        numValidationImagesEditField_2Label  matlab.ui.control.Label
        numTrainImagesEditField_2       matlab.ui.control.NumericEditField
        numTrainImagesEditField_2Label  matlab.ui.control.Label
        ValidationPatienceEditField_2   matlab.ui.control.NumericEditField
        ValidationPatienceEditField_2Label  matlab.ui.control.Label
        ValidationFrequencyEditField_2  matlab.ui.control.NumericEditField
        ValidationFrequencyEditField_2Label  matlab.ui.control.Label
        InitialLearnRateEditField_2     matlab.ui.control.NumericEditField
        InitialLearnRateEditField_2Label  matlab.ui.control.Label
        MaxEpochsEditField_2            matlab.ui.control.NumericEditField
        MaxEpochsEditField_2Label       matlab.ui.control.Label
        MiniBatchSizeEditField_2        matlab.ui.control.NumericEditField
        MiniBatchSizeEditField_2Label   matlab.ui.control.Label
        RedimensionarPanel_2            matlab.ui.container.Panel
        EXITOLabel_2                    matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        STARTButton_3                   matlab.ui.control.Button
        CARGARIMAGENButton_2            matlab.ui.control.Button
        MAIN_MENU                       matlab.ui.container.Panel
        THINGSPEAKButton                matlab.ui.control.Button
        atrasButton_2                   matlab.ui.control.Button
        CLASSIFICATIONButton_2          matlab.ui.control.Button
        TRAININGButton                  matlab.ui.control.Button
        TrainingAlexNetPanel            matlab.ui.container.Panel
        Atras                           matlab.ui.control.Button
        PanelBotones                    matlab.ui.container.Panel
        AjustesButton                   matlab.ui.control.Button
        RedimensionarButton             matlab.ui.control.Button
        PanelEmpty                      matlab.ui.container.Panel
        ajustarPanel                    matlab.ui.container.Panel
        WeightsCheckBox                 matlab.ui.control.CheckBox
        NetworkAnalyzerCheckBox         matlab.ui.control.CheckBox
        LayersAnalyzerCheckBox          matlab.ui.control.CheckBox
        ValidationImagesCheckBox        matlab.ui.control.CheckBox
        TrainingImagesCheckBox          matlab.ui.control.CheckBox
        RESETButton                     matlab.ui.control.Button
        PLAYButton                      matlab.ui.control.Button
        numValidationImagesEditField    matlab.ui.control.NumericEditField
        numValidationImagesEditFieldLabel  matlab.ui.control.Label
        numTrainImagesEditField         matlab.ui.control.NumericEditField
        numTrainImagesEditFieldLabel    matlab.ui.control.Label
        ValidationPatienceEditField     matlab.ui.control.NumericEditField
        ValidationPatienceEditFieldLabel  matlab.ui.control.Label
        ValidationFrequencyEditField    matlab.ui.control.NumericEditField
        ValidationFrequencyEditFieldLabel  matlab.ui.control.Label
        InitialLearnRateEditField       matlab.ui.control.NumericEditField
        InitialLearnRateEditFieldLabel  matlab.ui.control.Label
        MaxEpochsEditField              matlab.ui.control.NumericEditField
        MaxEpochsEditFieldLabel         matlab.ui.control.Label
        MiniBatchSizeEditField          matlab.ui.control.NumericEditField
        MiniBatchSizeEditFieldLabel     matlab.ui.control.Label
        RedimensionarPanel              matlab.ui.container.Panel
        EXITOLabel                      matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        STARTButton_2                   matlab.ui.control.Button
        CARGARIMAGENButton              matlab.ui.control.Button
        SELECCINDEMODELODEREDPanel      matlab.ui.container.Panel
        atrasButton                     matlab.ui.control.Button
        GOOGLENETButton_2               matlab.ui.control.Button
        ALEXNETButton_2                 matlab.ui.control.Button
        ClassificationPanel             matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        GOOGLENETopc                    matlab.ui.control.RadioButton
        ALEXNETopc                      matlab.ui.control.RadioButton
        SEARCHERLabel                   matlab.ui.control.Label
        searcherImage                   matlab.ui.control.Image
        COMPLETELabel                   matlab.ui.control.Label
        PROGRESSEditField               matlab.ui.control.NumericEditField
        PROGRESSLabel                   matlab.ui.control.Label
        videoframe                      matlab.ui.control.Image
        Atras_3                         matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        stopButton                      matlab.ui.control.Button
        cargarVideoButton               matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function cargarValoresTrainingAlex(app, ~)
            tempX = load('ajustesTrainingAlex.mat');
            app.MiniBatchSizeEditField.Value = tempX.MiniBatchSizeX;
            app.MaxEpochsEditField.Value = tempX.MaxEpochsX;
            app.InitialLearnRateEditField.Value = tempX.InitialLearnRateX;
            app.ValidationFrequencyEditField.Value = tempX.ValidationFrequencyX;
            app.ValidationPatienceEditField.Value = tempX.ValidationPatienceX;
            app.numTrainImagesEditField.Value = tempX.numTrainImages;
            app.numValidationImagesEditField.Value = tempX.numValidationImages;
            
            clear tempX;
        end
        
        function cargarValoresTrainingGoogle(app, ~)
            tempX = load('ajustesTrainingGoogle.mat');
            app.MiniBatchSizeEditField_2.Value = tempX.MiniBatchSizeX;
            app.MaxEpochsEditField_2.Value = tempX.MaxEpochsX;
            app.InitialLearnRateEditField_2.Value = tempX.InitialLearnRateX;
            app.ValidationFrequencyEditField_2.Value = tempX.ValidationFrequencyX;
            app.ValidationPatienceEditField_2.Value = tempX.ValidationPatienceX;
            app.numTrainImagesEditField_2.Value = tempX.numTrainImages;
            app.numValidationImagesEditField_2.Value = tempX.numValidationImages;
            
            clear tempX;
        end
        
        function cnnTrainingAlexNet(app,~)
            
            imds = imageDatastore('VehiculosAlexNet',...
                'IncludeSubfolders',true,...
                'LabelSource','foldernames');
            
            [imdsTrain,imdsValidation] = splitEachLabel(imds,0.9,'randomized');
            numTrainImages = numel(imdsTrain.Labels);
            idx = randperm(numTrainImages,app.numTrainImagesEditField.Value);
 
            if(app.TrainingImagesCheckBox.Value==true)
                figure('Name','Training Images','NumberTitle','off');
                raizCuadrada= realsqrt(app.numTrainImagesEditField.Value);
                if (mod(raizCuadrada,1)~=0) %si es decimal
                    raizCuadrada=floor(raizCuadrada)+1;
                end        
               
                for i = 1:app.numTrainImagesEditField.Value
                   subplot(raizCuadrada,raizCuadrada,i)
                   I = readimage(imdsTrain,idx(i));
                   imshow(I); 
                end
            end
            
            
            % load netlayer
            net = alexnet;
         
            % Análisis de la red al cargarla
            if(app.NetworkAnalyzerCheckBox.Value==true)
                 analyzeNetwork(net);
            end
           
            
            inputSize = net.Layers(1).InputSize;
            
            %% Seguimos las instrucciones teniendo en cuenta lo descrito en la siguiente dirección:
            % https://es.mathworks.com/help/nnet/ref/alexnet.html#bvnzu37
            
            % Replace Final Layers
            % The last three layers of the pretrained network net are configured for 1000 classes. These three layers must be fine-tuned for the new classification problem. Extract all layers, except the last three, from the pretrained network.
            
            layersTransfer = net.Layers(1:end-3);
            
            %Transfer the layers to the new classification task by replacing the last three layers with a fully connected layer, a softmax layer, and a classification output layer. Specify the options of the new fully connected layer according to the new data. Set the fully connected layer to have the same size as the number of classes in the new data. To learn faster in the new layers than in the transferred layers, increase the WeightLearnRateFactor and BiasLearnRateFactor values of the fully connected layer.
            numClasses = numel(categories(imdsTrain.Labels));
            
            layers = [
                layersTransfer
                fullyConnectedLayer(numClasses,'WeightLearnRateFactor',20,'BiasLearnRateFactor',20)
                softmaxLayer
                classificationLayer];
            
            %% Train Network
            %The network requires input images of size 227-by-227-by-3, but the images in the image datastores have different sizes. Use an augmented image datastore to automatically resize the training images. Specify additional augmentation operations to perform on the training images: randomly flip the training images along the vertical axis, and randomly translate them up to 30 pixels horizontally and vertically. Data augmentation helps prevent the network from overfitting and memorizing the exact details of the training images.
            
            pixelRange = [-30 30];
            imageAugmenter = imageDataAugmenter( ...
                'RandXReflection',true, ...
                'RandXTranslation',pixelRange, ...
                'RandYTranslation',pixelRange);
            
            %% OJO augmentedImageDatastore sólo funciona para Matlab 2018a
            % augimdsTrain = augmentedImageDatastore(inputSize(1:2),imdsTrain, ...
            %    'DataAugmentation',imageAugmenter);
            
            %%% To automatically resize the validation images without performing further data augmentation, use an augmented image datastore without specifying any additional preprocessing operations.
            %augimdsValidation = augmentedImageDatastore(inputSize(1:2),imdsValidation);
            
            %%%Specify the training options. For transfer learning, keep the features from the early layers of the pretrained network (the transferred layer weights). To slow down learning in the transferred layers, set the initial learning rate to a small value. In the previous step, you increased the learning rate factors for the fully connected layer to speed up learning in the new final layers. This combination of learning rate settings results in fast learning only in the new layers and slower learning in the other layers. When performing transfer learning, you do not need to train for as many epochs. An epoch is a full training cycle on the entire training data set. Specify the mini-batch size and validation data. The software validates the network every ValidationFrequency iterations during training.
         
            options = trainingOptions('sgdm', ...
                'MiniBatchSize',app.MiniBatchSizeEditField.Value, ...
                'MaxEpochs',app.MaxEpochsEditField.Value, ...
                'InitialLearnRate',app.InitialLearnRateEditField.Value, ...
                'ValidationData',imdsValidation, ...
                'ValidationFrequency',app.ValidationFrequencyEditField.Value, ...
                'ValidationPatience',app.ValidationPatienceEditField.Value, ...
                'Verbose',false, ...
                'Plots','training-progress');
            
            % Análisis de la red tras la reestructuración
            if(app.LayersAnalyzerCheckBox.Value==true)
                analyzeNetwork(layers)
            end
            
            %Train the network that consists of the transferred and new layers. By default, trainNetwork uses a GPU if one is available (requires Parallel Computing Toolbox™ and a CUDA® enabled GPU with compute capability 3.0 or higher). Otherwise, it uses a CPU. You can also specify the execution environment by using the 'ExecutionEnvironment' name-value pair argument of trainingOptions.
            netTransfer = trainNetwork(imdsTrain,layers,options);
            
            save netTransferAlex netTransfer
            
            %% Classify Validation Images
            % Classify the validation images using the fine-tuned network.
            
            [YPred,scores] = classify(netTransfer,imdsValidation);
            %Display four sample validation images with their predicted labels.
            
            idx = randperm(numel(imdsValidation.Files),app.numValidationImagesEditField.Value);
            
            if(app.ValidationImagesCheckBox.Value==true)
                figure('Name','Validation Images','NumberTitle','off');  
                raizCuadrada= realsqrt(app.numValidationImagesEditField.Value);
                if (mod(raizCuadrada,1)~=0) %si es decimal
                    raizCuadrada=floor(raizCuadrada)+1;
                end
               
                for i = 1:app.numValidationImagesEditField.Value
                    subplot(raizCuadrada,raizCuadrada,i)
                    I = readimage(imdsValidation,idx(i));
                    imshow(I)
                    label = YPred(idx(i));
                    title(string(label));
                   
                end
            
            end
            
            
            %% Visualización de los pesos
            
            % Get the network weights for the second convolutional layer
            w1 = netTransfer.Layers(2).Weights;
            
            % Scale and resize the weights for visualization
            w1 = mat2gray(w1);
            w1 = imresize(w1,5);
            
            % Display a montage of network weights. There are 96 individual sets of
            % weights in the first layer.
            if(app.WeightsCheckBox.Value==true)
                figure('Name','Weights','NumberTitle','off');
                montage(w1)
                title('First convolutional layer weights')
            end
            
        end
       
        
        function  cnnTrainingGoogleNet(app,~)
            
            imds = imageDatastore('VehiculosGoogleNet',...
                'IncludeSubfolders',true,...
                'LabelSource','foldernames');
            
            [imdsTrain,imdsValidation] = splitEachLabel(imds,0.9,'randomized');
            numTrainImages = numel(imdsTrain.Labels);
            idx = randperm(numTrainImages,app.numTrainImagesEditField_2.Value);
            
            if(app.TrainingImagesCheckBox_2.Value==true)
                figure('Name','Training Images','NumberTitle','off');
                raizCuadrada= realsqrt(app.numTrainImagesEditField_2.Value);
                if (mod(raizCuadrada,1)~=0) %si es decimal
                    raizCuadrada=floor(raizCuadrada)+1;
                end        
               
                for i = 1:app.numTrainImagesEditField_2.Value
                   subplot(raizCuadrada,raizCuadrada,i)
                   I = readimage(imdsTrain,idx(i));
                   imshow(I); 
                end
            end

            net = googlenet;
            
            lgraph = layerGraph(net);
            
            newlgraph = removeLayers(lgraph,'loss3-classifier');
            newlgraph = removeLayers(newlgraph,'prob');
            newlgraph = removeLayers(newlgraph,'output');
            
            %Transfer the layers to the new classification task by replacing the last three layers with a fully connected layer, a softmax layer, and a classification output layer. Specify the options of the new fully connected layer according to the new data. Set the fully connected layer to have the same size as the number of classes in the new data. To learn faster in the new layers than in the transferred layers, increase the WeightLearnRateFactor and BiasLearnRateFactor values of the fully connected layer.
            numClasses = numel(categories(imdsTrain.Labels));
            
            larray = [
                fullyConnectedLayer(numClasses,'WeightLearnRateFactor',20,'BiasLearnRateFactor',20, 'Name','fc1')
                softmaxLayer('Name','soft')
                classificationLayer('Name','c1')];
            
            newlgraph1 = addLayers(newlgraph,larray);
            
            lgraphFinal = connectLayers(newlgraph1,'pool5-drop_7x7_s1','fc1');
            
            if(app.NetworkAnalyzerCheckBox_2.Value == true)
                 analyzeNetwork(lgraphFinal);
            end
            
            %inputSize = net.Layers(1).InputSize;
            
            %% Train Network
            %The network requires input images of size 227-by-227-by-3, but the images in the image datastores have different sizes. Use an augmented image datastore to automatically resize the training images. Specify additional augmentation operations to perform on the training images: randomly flip the training images along the vertical axis, and randomly translate them up to 30 pixels horizontally and vertically. Data augmentation helps prevent the network from overfitting and memorizing the exact details of the training images.
            
            pixelRange = [-30 30];
            imageAugmenter = imageDataAugmenter( ...
                'RandXReflection',true, ...
                'RandXTranslation',pixelRange, ...
                'RandYTranslation',pixelRange);
            
            %% OJO augmentedImageDatastore sólo funciona para Matlab 2018a
            % augimdsTrain = augmentedImageDatastore(inputSize(1:2),imdsTrain, ...
            %    'DataAugmentation',imageAugmenter);
            
            %%% To automatically resize the validation images without performing further data augmentation, use an augmented image datastore without specifying any additional preprocessing operations.
            %augimdsValidation = augmentedImageDatastore(inputSize(1:2),imdsValidation);
            
            %%%Specify the training options. For transfer learning, keep the features from the early layers of the pretrained network (the transferred layer weights). To slow down learning in the transferred layers, set the initial learning rate to a small value. In the previous step, you increased the learning rate factors for the fully connected layer to speed up learning in the new final layers. This combination of learning rate settings results in fast learning only in the new layers and slower learning in the other layers. When performing transfer learning, you do not need to train for as many epochs. An epoch is a full training cycle on the entire training data set. Specify the mini-batch size and validation data. The software validates the network every ValidationFrequency iterations during training.
            
            options = trainingOptions('sgdm', ...
                'MiniBatchSize',app.MiniBatchSizeEditField_2.Value, ...
                'MaxEpochs',app.MaxEpochsEditField_2.Value, ...
                'InitialLearnRate',app.InitialLearnRateEditField_2.Value, ...
                'ValidationData',imdsValidation, ...
                'ValidationFrequency',app.ValidationFrequencyEditField_2.Value, ...
                'ValidationPatience',app.ValidationPatienceEditField_2.Value, ...
                'Verbose',false, ...
                'Plots','training-progress');
            
            %Train the network that consists of the transferred and new layers. By default, trainNetwork uses a GPU if one is available (requires Parallel Computing Toolbox™ and a CUDA® enabled GPU with compute capability 3.0 or higher). Otherwise, it uses a CPU. You can also specify the execution environment by using the 'ExecutionEnvironment' name-value pair argument of trainingOptions.
            netTransfer = trainNetwork(imdsTrain,lgraphFinal,options);
            
            save netTransferGoogle netTransfer
            
            %% Classify Validation Images
            % Classify the validation images using the fine-tuned network.
            
            [YPred,scores] = classify(netTransfer,imdsValidation);
            %Display four sample validation images with their predicted labels.
            
            idx = randperm(numel(imdsValidation.Files),app.numValidationImagesEditField_2.Value);
            
             if(app.ValidationImagesCheckBox_2.Value==true)
                figure('Name','Validation Images','NumberTitle','off');  
                raizCuadrada= realsqrt(app.numValidationImagesEditField_2.Value);
                if (mod(raizCuadrada,1)~=0) %si es decimal
                    raizCuadrada=floor(raizCuadrada)+1;
                end
               
                for i = 1:app.numValidationImagesEditField_2.Value
                    subplot(raizCuadrada,raizCuadrada,i)
                    I = readimage(imdsValidation,idx(i));
                    imshow(I)
                    label = YPred(idx(i));
                    title(string(label));
                   
                end
            
             end
             
            %% Visualización de los pesos
            
            % Get the network weights for the second convolutional layer
            w1 = netTransfer.Layers(2).Weights;
            
            % Scale and resize the weights for visualization
            w1 = mat2gray(w1);
            w1 = imresize(w1,5);
            
            % Display a montage of network weights. There are 96 individual sets of
            % weights in the first layer.
            if(app.WeightsCheckBox_2.Value == true)
                figure('Name','Weights','NumberTitle','off');
                montage(w1)
                title('First convolutional layer weights')
            end
        end           
        
        function cnnClasification(app,~)
            
            ChannelId = 1564684;
            readAPIKey = 'DEJWLBEM6EXB9Z8I';
            writeAPIKey = '7RNEU2X3CE2UEKLA';
            vehiculos = 0, coches = 0, buses = 0, motos = 0, camionesFurgos = 0; 


            global stop;
            sssMPEG = './video/clasificacionFinal.mp4';
            videoMPEG = VideoWriter(sssMPEG,'MPEG-4');
            open(videoMPEG);
            if(app.ALEXNETopc.Value)
              load netTransferAlex;
            elseif(app.GOOGLENETopc.Value)
              load netTransferGoogle;
            end
            
            cont = 0; 
            
            warning('off','all');
            sz = netTransfer.Layers(1).InputSize;
            [aa, bb]=uigetfile({'*.*','mp4'});
            NombreVideo = strcat(bb,aa);
            warning('on','all');       
            
           
            if(NombreVideo~="")
                
                vidReader = VideoReader(NombreVideo);
                opticFlow = opticalFlowFarneback;
                %opticFlow = opticalFlowLK;
                reset(opticFlow);
                    
                frameRGB = read(vidReader,1);
                [M,N,~] = size(frameRGB);
                frameRGBprev = frameRGB; 
                frameGrayprev = rgb2gray(frameRGB);
                    
                NFrames = vidReader.NumFrames;
                %% Estimate Optical Flow of each frame
                
                start = 1610; 
                for i=start:1:NFrames %despreciamos el
            
                  if(stop == true)
                      break;
                  end
                 
                  app.PROGRESSEditField.Value=((i-start)/(NFrames-start))*100;  
                 
                  %while hasFrame(vidReader)
                  frameRGB = read(vidReader,i);
                  %frameRGBcopia = frameRGB;
                  %frameRGB = readFrame(vidReader);
                  frameGray = rgb2gray(frameRGB);
                  flow = estimateFlow(opticFlow,frameGray);
                  f=figure("Visible","off");
                  imshow(frameRGB);
                  impixelinfo;
                  hold on
                  % Plot the flow vectors
                  plot(flow,'DecimationFactor',[25 25],'ScaleFactor', 2);
                  % Find the handle to the quiver object
                  q = findobj(gca,'type','Quiver');
                  % Change the color of the arrows to red
                  q.Color = 'r';
                  drawnow
                  hold off
                  saveas(f,['./frames/Figuraimg' num2str(cont) '.jpg']);   
                  app.videoframe.ImageSource = ['./frames/Figuraimg' num2str(cont) '.jpg'];  
                  cont = cont + 1;
                  if i > 2
                    MagnitudFlow    = mat2gray(flow.Magnitude);
                    OrientacionFlow = flow.Orientation;
                    level = mean2(MagnitudFlow)+std2(MagnitudFlow);
                    BWMagFlow = MagnitudFlow > level;
                
                    [Labels,Nlabels] = bwlabel(BWMagFlow);
                    %figure(3); imagesc(Labels); impixelinfo; colorbar
                    RProp   = regionprops(Labels,'all');
                    RPropRed   = regionprops(Labels,frameRGB(:,:,1),'all');
                    RPropGreen = regionprops(Labels,frameRGB(:,:,2),'all');
                    RPropBlue  = regionprops(Labels,frameRGB(:,:,3),'all');
                    RPropOrientacion  = regionprops(Labels,OrientacionFlow,'all');
                    
                    % Habría que trabajar un poco esto para restringir más las áreas
                    % candidatas
                    AreasCandidatas = zeros(1,Nlabels);
                    for j=1:1:Nlabels
                      if RProp(j).Area > 500
                        AreasCandidatas(j) = 1;
                      end
                    end
                    
                  amp = 0;
                  for h=1:1:Nlabels
                    if AreasCandidatas(h) == 1
                      XSupIzda =  round(RProp(h).BoundingBox(1)+amp);
                      if XSupIzda <=0; XSupIzda = 1; end
                      YSupIzda =  round(RProp(h).BoundingBox(2)+amp);  
                      if YSupIzda <=0; YSupIzda = 1; end
                    
                      XSupDcha =  round(XSupIzda + RProp(h).BoundingBox(3) + amp);
                      if XSupDcha > N; XSupDcha = N; end
                      YSupDcha =  YSupIzda; 
                     
                      XInfIzda =  XSupIzda;
                      YInfIzda =  round(YSupIzda + RProp(h).BoundingBox(4) + amp);
                      if YInfIzda > M; YInfIzda = M; end
                
                      XInfDcha =  XSupDcha; 
                      YInfDcha =  YInfIzda;
                    
                      Recorte = frameRGB(YSupIzda:1:YInfIzda,XSupIzda:1:XSupDcha,:);
                      RecorteBW = BWMagFlow(YSupIzda:1:YInfIzda,XSupIzda:1:XSupDcha,:);
                
                      [aar, bbr, ssr] = size(Recorte);
                      R = imresize(Recorte, [sz(1) sz(2)], 'bilinear');       
                      %f2=figure("Visible","off")
                      app.searcherImage.ImageSource= R;
                    
                      %imshow(R); 
                      hold on;
    
                      %% Clasifcación propiamente dicha
                      [label, Error]  = classify(netTransfer,R);
                      [MEt,MaxEt] = max(Error);
                      disp('Label ='); disp(label)
                      disp('Error ='); disp(Error)
                      
                      % Aquí debemos tomar una decisión para determinar si el coche va en
                      % buen sentido o mal. Teniendo en cuenta la posición de la cámara en
                      % la carretera. Por la parte izquierda el flujo tendrá una
                      % orientación diferente a la derecha. 
                      Orientacion = RPropOrientacion(h).MeanIntensity;
                
                      if (label ~= 'Asfalto') && (label ~= 'Lineas') && (label ~= 'Muro')... 
                         && (MEt >= 0.5)... 
                         && RPropOrientacion(h).Centroid(2) > 500 && RPropOrientacion(h).Centroid(2) < 950 
                        %figure(5); bar(Error)
                      
                        switch label
                        case 'Bus'
                          color = 'yellow'; texto = 'Bus';
                          buses = buses + 1; vehiculos = vehiculos + 1;
                        case 'CamionFurgo'
                          color = 'white'; texto = 'Camion-furgo';
                          camionesFurgos = camionesFurgos + 1; vehiculos = vehiculos + 1;
                        case 'CocheDelantera'
                          color = 'blue'; texto = 'Car Frontal';
                          coches = coches + 1; vehiculos = vehiculos + 1;
                        case 'CocheTrasera'
                          color = 'red'; texto = 'Car Trasera';
                          coches = coches + 1; vehiculos = vehiculos + 1;
                        case 'Moto'
                          color = 'green'; texto = 'Moto';
                          motos = motos + 1; vehiculos = vehiculos + 1;
                        case 'Asfalto'
                          color = 'black'; texto = 'Asfalto';
                        case 'Lineas'
                          color = 'black'; texto = 'Lineas';
                        case 'Muro'
                          color = 'black'; texto = 'Muro';
                        end
    
                        %f3=figure("Visible",'off');
                        hold on; text(XSupDcha,YSupDcha,texto)
                        line([XSupIzda,XSupDcha],[YSupIzda,YSupDcha],'LineWidth',3,'Color',color);
                        line([XSupIzda,XInfIzda],[YSupIzda,YInfIzda],'LineWidth',3,'Color',color);
                        line([XSupDcha,XInfDcha],[YSupDcha,YInfDcha],'LineWidth',3,'Color',color);
                        line([XInfIzda,XInfDcha],[YInfIzda,YInfDcha],'LineWidth',3,'Color',color);
                        hold off
                        saveas(f,['./frames/Figuraimg' num2str(cont) '.jpg']);
                        app.videoframe.ImageSource = ['./frames/Figuraimg' num2str(cont) '.jpg'];
                        cont = cont + 1;
                      end
                    end
                  end
                  end
                   saveas(f,'./video/Figura1.bmp');
                   frameFigure = imread('./video/Figura1.bmp');
                   if i > start+2
                     writeVideo(videoMPEG,frameFigure);
                   end   
                end
                coches = round(coches / vehiculos * 100, 0);
                buses = round(buses / vehiculos * 100, 0);
                motos = round(motos / vehiculos * 100, 0);
                camionesFurgos = round(camionesFurgos / vehiculos * 100, 0);
                textoTweet = ['Coches: ', num2str(coches),'%',newline,'Buses: ', num2str(buses),'%',newline,'Motos: ', num2str(motos),'%',newline,'Camiones-Furgo: ', num2str(camionesFurgos),'%'];
                thingSpeakWrite(ChannelId,'Fields',[2,3,4,5],'Values',{coches,buses,motos,camionesFurgos},'WriteKey',writeAPIKey);
                webwrite('https://api.thingspeak.com/apps/thingtweet/1/statuses/update','api_key', 'UQO5LWCHMPMJKXJ2','status',textoTweet);
                pause(15);
                
                close(videoMPEG); %se cierra el video
                if(stop == true)  
                    app.COMPLETELabel.Visible='off';
                    app.videoframe.ImageSource = 'play.png';
                    app.searcherImage.ImageSource= 'imagenes.png';  
                else  
                    app.COMPLETELabel.Visible='on'; 
                end
            end
            data = thingSpeakRead(ChannelId,'Fields',[2,3,4,5],'NumPoints',10,'OutputFormat','TimeTable','ReadKey',readAPIKey);
            app.UITable.Data = timetable2table(data);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.PrincipalPanel.Visible = 'off';
            app.MAIN_MENU.Visible = 'on';
        end

        % Button pushed function: atrasButton
        function atrasButtonPushed(app, event)
            app.SELECCINDEMODELODEREDPanel.Visible="off";
            app.MAIN_MENU.Visible="on";
        end

        % Button pushed function: GOOGLENETButton_2
        function GOOGLENETButtonPushed(app, event)
            app.SELECCINDEMODELODEREDPanel.Visible="off";
            app.PanelEmpty_2.Title="Ajustes";
            app.ajustarPanel_2.Visible="on";
            app.RedimensionarPanel_2.Visible="off";
            app.TrainingGoogleNetPanel.Visible="on";
        end

        % Button pushed function: ALEXNETButton_2
        function ALEXNETButtonPushed(app, event)
            app.SELECCINDEMODELODEREDPanel.Visible="off";
            app.PanelEmpty.Title = 'Ajustes';
            app.ajustarPanel.Visible="on";
            app.RedimensionarPanel.Visible="off";
            app.TrainingAlexNetPanel.Visible="on";
        end

        % Button pushed function: atrasButton_2
        function atrasButton_2Pushed(app, event)
            app.MAIN_MENU.Visible="off";
            app.PrincipalPanel.Visible="on";
        end

        % Callback function
        function atrasButton_3Pushed(app, event)
            app.GOOGLENETPanel.Visible="off";
            app.SELECCINDEMODELODEREDPanel.Visible="on";
        end

        % Button pushed function: RedimensionarButton
        function RedimensionarButtonPushed(app, event)
            app.PanelEmpty.Title = 'Redimensionar';
            app.RedimensionarPanel.Visible="on";
            app.Image.ImageSource = "interrogacion.jpg";
            app.ajustarPanel.Visible="off";
            app.EXITOLabel.Visible="off";
            app.STARTButton_2.Visible="off";
        end

        % Button pushed function: AjustesButton
        function AjustesButtonPushed(app, event)
            app.PanelEmpty.Title = 'Ajustes';
            app.RedimensionarPanel.Visible="off";
            cargarValoresTrainingAlex(app);
            app.WeightsCheckBox.Value=true;
            app.NetworkAnalyzerCheckBox.Value=true;
            app.LayersAnalyzerCheckBox.Value=true;
            app.ValidationImagesCheckBox.Value=true;
            app.TrainingImagesCheckBox.Value=true;
            app.ajustarPanel.Visible="on";
        end

        % Button pushed function: CARGARIMAGENButton
        function CARGARIMAGENButtonPushed(app, event)
            ant=app.Image.ImageSource;
            app.RedimensionarPanel.Visible="on";
            app.EXITOLabel.Visible="off";
            app.STARTButton_2.Visible="off";
            warning('off','all');
            [aa, bb]=uigetfile({'*.png';'*.jpg'}, 'Cargar imagen');
            NombreImg = strcat(bb,aa);
           if(NombreImg~="")
                app.STARTButton_2.Visible = 'on';
                app.Image.ImageSource = NombreImg;
            else
                app.Image.ImageSource = ant;
           end
           warning('on','all');
            
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            imagen = imread(app.Image.ImageSource);
            imagen = imresize(imagen, [227 227]);
            imwrite(imagen, app.Image.ImageSource);
            app.EXITOLabel.Visible="on";
          
        end

        % Button pushed function: Atras
        function AtrasButtonPushed(app, event)
            app.TrainingAlexNetPanel.Visible="off";
            app.SELECCINDEMODELODEREDPanel.Visible= "on";
        end

        % Button pushed function: TRAININGButton
        function TRAININGButtonPushed(app, event)
            app.MAIN_MENU.Visible="off";
            app.SELECCINDEMODELODEREDPanel.Visible="on";
        end

        % Callback function
        function TRAININGButton_2Pushed(app, event)
            app.GOOGLENETPanel.Visible="off";
            app.PanelEmpty_2.Title="";
            app.ajustarPanel_2.Visible="off";
            app.RedimensionarPanel_2.Visible="off";
            app.TrainingGoogleNetPanel.Visible="on";
        end

        % Button pushed function: Atras_2
        function Atras_2ButtonPushed(app, event)
            app.TrainingGoogleNetPanel.Visible="off";
            app.SELECCINDEMODELODEREDPanel.Visible="on";
        end

        % Button pushed function: AjustesButton_2
        function AjustesButton_2Pushed(app, event)
            app.PanelEmpty_2.Title = 'Ajustes';
            cargarValoresTrainingGoogle(app);
            app.RedimensionarPanel_2.Visible="off";
            app.WeightsCheckBox_2.Value=true;
            app.NetworkAnalyzerCheckBox_2.Value=true;
            app.ValidationImagesCheckBox_2.Value=true;
            app.TrainingImagesCheckBox_2.Value=true;
            app.ajustarPanel_2.Visible="on";
        end

        % Button pushed function: RedimensionarButton_2
        function RedimensionarButton_2Pushed(app, event)
            app.PanelEmpty_2.Title = 'Redimensionar';
            app.RedimensionarPanel_2.Visible="on";
            app.Image_2.ImageSource = "interrogacion.jpg";
            app.ajustarPanel_2.Visible="off";
            app.EXITOLabel_2.Visible="off";
            app.STARTButton_3.Visible="off";
        end

        % Button pushed function: STARTButton_3
        function STARTButton_3Pushed(app, event)
            imagen = imread(app.Image_2.ImageSource);
            imagen = imresize(imagen, [224 224]);
            imwrite(imagen, app.Image_2.ImageSource);
            app.EXITOLabel_2.Visible="on";
        end

        % Button pushed function: CARGARIMAGENButton_2
        function CARGARIMAGENButton_2Pushed(app, event)
            ant=app.Image_2.ImageSource;
            app.RedimensionarPanel_2.Visible="on";
            app.EXITOLabel_2.Visible="off";
            app.STARTButton_3.Visible="off";
            warning('off','all');
            [aa, bb]=uigetfile({'*.png';'*.jpg'}, 'Cargar imagen');
            NombreImg = strcat(bb,aa);
            if(NombreImg~="")
                app.STARTButton_3.Visible = 'on';
                app.Image_2.ImageSource = NombreImg;
            else
                app.Image_2.ImageSource = ant;
            end
            warning('on','all');
            
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            cargarValoresTrainingAlex(app);
            app.WeightsCheckBox.Value=true;
            app.NetworkAnalyzerCheckBox.Value=true;
            app.LayersAnalyzerCheckBox.Value=true;
            app.ValidationImagesCheckBox.Value=true;
            app.TrainingImagesCheckBox.Value=true;
        end

        % Button pushed function: Atras_3
        function Atras_3ButtonPushed(app, event)
            app.ClassificationPanel.Visible="off";
            app.MAIN_MENU.Visible="on";
        end

        % Button pushed function: CLASSIFICATIONButton_2
        function CLASSIFICATIONButton_2Pushed(app, event)
            app.MAIN_MENU.Visible="off";
            app.ClassificationPanel.Visible="on";
            app.stopButton.Enable='off';
            app.videoframe.ImageSource='play.png';
            app.searcherImage.ImageSource='imagenes.png';
            app.PROGRESSEditField.Value=0;
            app.COMPLETELabel.Visible='off';
        end

        % Button pushed function: RESETButton_2
        function RESETButton_2Pushed(app, event)
            cargarValoresTrainingGoogle(app);
            app.WeightsCheckBox_2.Value=true;
            app.NetworkAnalyzerCheckBox_2.Value=true;
            app.ValidationImagesCheckBox_2.Value=true;
            app.TrainingImagesCheckBox_2.Value=true;
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            app.PLAYButton.Enable="off";
            app.RESETButton.Enable="off";
            app.Atras.Enable="off";
            app.RedimensionarButton.Enable="off";
            app.MaxEpochsEditField.Enable="off";
            app.MiniBatchSizeEditField.Enable="off";
            app.InitialLearnRateEditField.Enable="off";
            app.ValidationFrequencyEditField.Enable="off";
            app.ValidationPatienceEditField.Enable="off";
            app.numTrainImagesEditField.Enable="off";
            app.numValidationImagesEditField.Enable="off";
            app.TrainingImagesCheckBox.Enable="off";
            app.ValidationImagesCheckBox.Enable="off";
            app.NetworkAnalyzerCheckBox.Enable="off";
            app.LayersAnalyzerCheckBox.Enable="off";
            app.WeightsCheckBox.Enable="off";
            cnnTrainingAlexNet(app);
            app.PLAYButton.Enable="on";
            app.RESETButton.Enable="on";
            app.Atras.Enable="on";
            app.RedimensionarButton.Enable="on";
            app.MaxEpochsEditField.Enable="on";
            app.MiniBatchSizeEditField.Enable="on";
            app.InitialLearnRateEditField.Enable="on";
            app.ValidationFrequencyEditField.Enable="on";
            app.ValidationPatienceEditField.Enable="on";
            app.numTrainImagesEditField.Enable="on";
            app.numValidationImagesEditField.Enable="on";
            app.TrainingImagesCheckBox.Enable="on";
            app.ValidationImagesCheckBox.Enable="on";
            app.NetworkAnalyzerCheckBox.Enable="on";
            app.LayersAnalyzerCheckBox.Enable="on";
            app.WeightsCheckBox.Enable="on";
            
        end

        % Button pushed function: PLAYButton_2
        function PLAYButton_2Pushed(app, event)
            app.PLAYButton_2.Enable="off";
            app.Atras_2.Enable="off";
            app.RedimensionarButton_2.Enable="off";
            app.RESETButton_2.Enable="off";
            app.MaxEpochsEditField_2.Enable="off";
            app.MiniBatchSizeEditField_2.Enable="off";
            app.InitialLearnRateEditField_2.Enable="off";
            app.ValidationFrequencyEditField_2.Enable="off";
            app.ValidationPatienceEditField_2.Enable="off";
            app.numTrainImagesEditField_2.Enable="off";
            app.numValidationImagesEditField_2.Enable="off";
            app.TrainingImagesCheckBox_2.Enable="off";
            app.ValidationImagesCheckBox_2.Enable="off";
            app.NetworkAnalyzerCheckBox_2.Enable="off";
            app.WeightsCheckBox_2.Enable="off";
            cnnTrainingGoogleNet(app);
            app.PLAYButton_2.Enable="on";
            app.Atras_2.Enable="on";
            app.RedimensionarButton_2.Enable="on";
            app.RESETButton_2.Enable="on";
            app.MaxEpochsEditField_2.Enable="on";
            app.MiniBatchSizeEditField_2.Enable="on";
            app.InitialLearnRateEditField_2.Enable="on";
            app.ValidationFrequencyEditField_2.Enable="on";
            app.ValidationPatienceEditField_2.Enable="on";
            app.numTrainImagesEditField_2.Enable="on";
            app.numValidationImagesEditField_2.Enable="on";
            app.TrainingImagesCheckBox_2.Enable="on";
            app.ValidationImagesCheckBox_2.Enable="on";
            app.NetworkAnalyzerCheckBox_2.Enable="on";
            app.WeightsCheckBox_2.Enable="on";
        end

        % Button pushed function: cargarVideoButton
        function cargarVideoButtonPushed(app, event)
            global stop; 
            stop = false; 
            app.videoframe.ImageSource='play.png';
            app.searcherImage.ImageSource='imagenes.png';
            app.stopButton.Enable='on';
            app.cargarVideoButton.Enable='off';
            app.Atras_3.Enable='off';
            app.PROGRESSEditField.Value=0; 
            app.COMPLETELabel.Visible='off';
            app.ALEXNETopc.Enable="off";
            app.GOOGLENETopc.Enable="off";
            mkdir frames;
            cnnClasification(app);
            rmdir frames s;
            app.stopButton.Enable='off';
            app.cargarVideoButton.Enable='on';
            app.ALEXNETopc.Enable="on";
            app.GOOGLENETopc.Enable="on";
            app.Atras_3.Enable='on';
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            global stop;
            stop = true;
            app.cargarVideoButton.Enable='on';
            app.videoframe.ImageSource='play.png';
            app.searcherImage.ImageSource='imagenes.png';
            app.stopButton.Enable='off';
            app.ButtonGroup.Enable="on";
            app.PROGRESSEditField.Value = 0;
        end

        % Button pushed function: THINGSPEAKButton
        function THINGSPEAKButtonPushed(app, event)
            app.MAIN_MENU.Visible="off";
            app.thingSpeakPanel.Visible="on";      
            ChannelId = 1564684;
            readAPIKey = 'DEJWLBEM6EXB9Z8I';
            data = thingSpeakRead(ChannelId,'Fields',[2,3,4,5],'NumPoints',20,'OutputFormat','TimeTable','ReadKey',readAPIKey);
            app.UITable.Data = timetable2table(data);
            app.UITable.ColumnName=["FECHA","COCHES","BUSES","MOTOS","CAMIONES-FURGOS"];
        end

        % Button pushed function: CHANNELButton
        function CHANNELButtonPushed(app, event)
            web("https://thingspeak.com/channels/1564684/shared_view");
        end

        % Button pushed function: Atras_4
        function Atras_4ButtonPushed(app, event)
            app.thingSpeakPanel.Visible="off";
            app.MAIN_MENU.Visible="on";
        end

        % Button pushed function: ACCEPTButton
        function ACCEPTButtonPushed(app, event)
            ChannelId = 1564684;
            readAPIKey = 'DEJWLBEM6EXB9Z8I';
            if(app.COCHESButton.Value)
                 data = thingSpeakRead(ChannelId,'Fields',2,'NumPoints',20,'OutputFormat','TimeTable','ReadKey',readAPIKey);
                 app.UITable.Data = timetable2table(data); 
                 app.UITable.ColumnName=["FECHA","COCHES"];
            elseif(app.MOTOSButton.Value)
                data = thingSpeakRead(ChannelId,'Fields',4,'NumPoints',20,'OutputFormat','TimeTable','ReadKey',readAPIKey);
                app.UITable.Data = timetable2table(data);
                app.UITable.ColumnName=["FECHA","MOTOS"];
            elseif(app.BUSESButton.Value)
                data = thingSpeakRead(ChannelId,'Fields',3,'NumPoints',20,'OutputFormat','TimeTable','ReadKey',readAPIKey);
                app.UITable.Data = timetable2table(data);
                 app.UITable.ColumnName=["FECHA","BUSES"];
            elseif(app.CAMIONESFURGOSButton.Value)
                data = thingSpeakRead(ChannelId,'Fields',5,'NumPoints',20,'OutputFormat','TimeTable','ReadKey',readAPIKey);
                app.UITable.Data = timetable2table(data);
                app.UITable.ColumnName=["FECHA","CAMIONES-FURGOS"];
            else
                data = thingSpeakRead(ChannelId,'Fields',[2,3,4,5],'NumPoints',20,'OutputFormat','TimeTable','ReadKey',readAPIKey);
                app.UITable.Data = timetable2table(data);
                app.UITable.ColumnName=["FECHA","COCHES","BUSES","MOTOS","CAMIONES-FURGOS"];
            end
            
        end

        % Button pushed function: ALLButton
        function ALLButtonPushed(app, event)
            ChannelId = 1564684;
            readAPIKey = 'DEJWLBEM6EXB9Z8I';
            data = thingSpeakRead(ChannelId,'Fields',[2,3,4,5],'NumPoints',20,'OutputFormat','TimeTable','ReadKey',readAPIKey);
            app.UITable.Data = timetable2table(data);
            app.UITable.ColumnName=["FECHA","COCHES","BUSES","MOTOS","CAMIONES-FURGOS"];
        end

        % Button pushed function: TWITTER_button
        function TWITTER_buttonButtonPushed(app, event)
            web("https://twitter.com/RecVehiculosTFG");
        end

        % Button pushed function: SENDTWEETButton
        function SENDTWEETButtonPushed(app, event)
            ChannelId = 1564684;
            readAPIKey = 'DEJWLBEM6EXB9Z8I';
            Field = 2;
            vehiculo = 'coches';
            date = app.DATEPicker.Value;
            date2 = date + ' 23:59:59';
            if(app.COCHESButton_2.Value)
                Field = 2;
                vehiculo = 'coches';
            elseif(app.BUSESButton_2.Value)
                Field = 3;
                vehiculo = 'buses';
            elseif(app.MOTOSButton_2.Value)
                Field = 4;
                vehiculo = 'motos';
            elseif(app.CAMIONESFURGOSButton_2.Value)
                Field = 5;
                vehiculo = 'camiones/furgonetas';
            end
            t = datetime(date,'InputFormat','dd-MMM-yyyy HH:mm:ss');
            t2 = datetime(date2,'InputFormat','dd-MMM-yyyy HH:mm:ss');
            data = thingSpeakRead(ChannelId,'Fields',Field,'DateRange',[t,t2],'ReadKey',readAPIKey);
            if(size(data) == 0)
                textoTweet = ['No hay flujo de tráfico en el día ', datestr(date)];
                webwrite('https://api.thingspeak.com/apps/thingtweet/1/statuses/update','api_key', 'UQO5LWCHMPMJKXJ2','status',textoTweet);
                pause(15);
            else
                i = 1;
                sum = 0;
                while(i <= length(data))
                     sum = sum + data(i,:);
                     i = i + 1;
                end
                media = sum / length(data);
                textoTweet = ['El flujo de tráfico de ', vehiculo,' en el día ', datestr(date), ' es del ', num2str(media), '%'];
                webwrite('https://api.thingspeak.com/apps/thingtweet/1/statuses/update','api_key', 'UQO5LWCHMPMJKXJ2','status',textoTweet);
                pause(15);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TFGUIFigure = uifigure('Visible', 'off');
            app.TFGUIFigure.Name = 'TFG';
            app.TFGUIFigure.Icon = 'conexion.png';
            app.ClassificationPanel = uipanel(app.TFGUIFigure);
            app.ClassificationPanel.Title = 'CLASSIFICATION';
            app.ClassificationPanel.Visible = 'off';
            app.Panel = uipanel(app.ClassificationPanel);
            app.cargarVideoButton = uibutton(app.Panel, 'push');
            app.cargarVideoButton.ButtonPushedFcn = createCallbackFcn(app, @cargarVideoButtonPushed, true);
            app.cargarVideoButton.Icon = 'boton-de-carga.png';
            app.cargarVideoButton.Text = '';
            app.stopButton = uibutton(app.Panel, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Icon = 'senal-de-stop.png';
            app.stopButton.Text = '';
            app.Atras_3 = uibutton(app.ClassificationPanel, 'push');
            app.Atras_3.ButtonPushedFcn = createCallbackFcn(app, @Atras_3ButtonPushed, true);
            app.Atras_3.Icon = 'atras.png';
            app.Atras_3.Text = '';
            app.videoframe = uiimage(app.ClassificationPanel);
            app.videoframe.ImageSource = 'play.png';
            app.PROGRESSLabel = uilabel(app.ClassificationPanel);
            app.PROGRESSLabel.Text = 'PROGRESS (%)';
            app.PROGRESSEditField = uieditfield(app.ClassificationPanel, 'numeric');
            app.PROGRESSEditField.Limits = [0 100];
            app.PROGRESSEditField.Editable = 'off';
            app.COMPLETELabel = uilabel(app.ClassificationPanel);
            app.COMPLETELabel.Visible = 'off';
            app.COMPLETELabel.Text = {'COMPLETE !'; ''};
            app.searcherImage = uiimage(app.ClassificationPanel);
            app.searcherImage.ImageSource = 'imagenes.png';
            app.SEARCHERLabel = uilabel(app.ClassificationPanel);
            app.SEARCHERLabel.Text = {'SEARCHER'; ''};
            app.ButtonGroup = uibuttongroup(app.ClassificationPanel);
            app.ALEXNETopc = uiradiobutton(app.ButtonGroup);
            app.ALEXNETopc.Text = {'ALEXNET'; ''};
            app.ALEXNETopc.Value = true;
            app.GOOGLENETopc = uiradiobutton(app.ButtonGroup);
            app.GOOGLENETopc.Text = {'GOOGLENET'; ''};
            app.SELECCINDEMODELODEREDPanel = uipanel(app.TFGUIFigure);
            app.SELECCINDEMODELODEREDPanel.Title = 'SELECCIÓN DE MODELO DE RED ';
            app.SELECCINDEMODELODEREDPanel.Visible = 'off';
            app.ALEXNETButton_2 = uibutton(app.SELECCINDEMODELODEREDPanel, 'push');
            app.ALEXNETButton_2.ButtonPushedFcn = createCallbackFcn(app, @ALEXNETButtonPushed, true);
            app.ALEXNETButton_2.Icon = 'A.png';
            app.ALEXNETButton_2.Text = 'ALEXNET';
            app.GOOGLENETButton_2 = uibutton(app.SELECCINDEMODELODEREDPanel, 'push');
            app.GOOGLENETButton_2.ButtonPushedFcn = createCallbackFcn(app, @GOOGLENETButtonPushed, true);
            app.GOOGLENETButton_2.Icon = 'G.png';
            app.GOOGLENETButton_2.Text = 'GOOGLENET';
            app.atrasButton = uibutton(app.SELECCINDEMODELODEREDPanel, 'push');
            app.atrasButton.ButtonPushedFcn = createCallbackFcn(app, @atrasButtonPushed, true);
            app.atrasButton.Icon = 'atras.png';
            app.atrasButton.Text = '';
            app.TrainingAlexNetPanel = uipanel(app.TFGUIFigure);
            app.TrainingAlexNetPanel.Title = 'TRAINING ALEXNET';
            app.TrainingAlexNetPanel.Visible = 'off';
            app.PanelEmpty = uipanel(app.TrainingAlexNetPanel);
            app.RedimensionarPanel = uipanel(app.PanelEmpty);
            app.RedimensionarPanel.Visible = 'off';
            app.CARGARIMAGENButton = uibutton(app.RedimensionarPanel, 'push');
            app.CARGARIMAGENButton.ButtonPushedFcn = createCallbackFcn(app, @CARGARIMAGENButtonPushed, true);
            app.CARGARIMAGENButton.Text = 'CARGAR IMAGEN';
            app.STARTButton_2 = uibutton(app.RedimensionarPanel, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Visible = 'off';
            app.STARTButton_2.Text = 'START';
            app.Image = uiimage(app.RedimensionarPanel);
            app.Image.ImageSource = 'interrogacion.jpg';
            app.EXITOLabel = uilabel(app.RedimensionarPanel);
            app.EXITOLabel.Visible = 'off';
            app.EXITOLabel.Text = '¡ÉXITO AL REDIMENSIONAR!';
            app.ajustarPanel = uipanel(app.PanelEmpty);
            app.ajustarPanel.Visible = 'off';
            app.MiniBatchSizeEditFieldLabel = uilabel(app.ajustarPanel);
            app.MiniBatchSizeEditFieldLabel.Text = 'MiniBatchSize';
            app.MiniBatchSizeEditField = uieditfield(app.ajustarPanel, 'numeric');
            app.MiniBatchSizeEditField.Limits = [4 32];
            app.MiniBatchSizeEditField.Value = 10;
            app.MaxEpochsEditFieldLabel = uilabel(app.ajustarPanel);
            app.MaxEpochsEditFieldLabel.Text = 'MaxEpochs';
            app.MaxEpochsEditField = uieditfield(app.ajustarPanel, 'numeric');
            app.MaxEpochsEditField.Limits = [1 30];
            app.MaxEpochsEditField.Value = 10;
            app.InitialLearnRateEditFieldLabel = uilabel(app.ajustarPanel);
            app.InitialLearnRateEditFieldLabel.Text = 'InitialLearnRate';
            app.InitialLearnRateEditField = uieditfield(app.ajustarPanel, 'numeric');
            app.InitialLearnRateEditField.Limits = [1e-05 0.01];
            app.InitialLearnRateEditField.Value = 0.0001;
            app.ValidationFrequencyEditFieldLabel = uilabel(app.ajustarPanel);
            app.ValidationFrequencyEditFieldLabel.Text = 'ValidationFrequency';
            app.ValidationFrequencyEditField = uieditfield(app.ajustarPanel, 'numeric');
            app.ValidationFrequencyEditField.Limits = [1 64];
            app.ValidationFrequencyEditField.Value = 3;
            app.ValidationPatienceEditFieldLabel = uilabel(app.ajustarPanel);
            app.ValidationPatienceEditFieldLabel.Text = 'ValidationPatience';
            app.ValidationPatienceEditField = uieditfield(app.ajustarPanel, 'numeric');
            app.ValidationPatienceEditField.Limits = [0 Inf];
            app.ValidationPatienceEditField.Editable = 'off';
            app.ValidationPatienceEditField.Value = Inf;
            app.numTrainImagesEditFieldLabel = uilabel(app.ajustarPanel);
            app.numTrainImagesEditFieldLabel.Text = 'numTrainImages';
            app.numTrainImagesEditField = uieditfield(app.ajustarPanel, 'numeric');
            app.numTrainImagesEditField.Limits = [1 64];
            app.numTrainImagesEditField.Value = 64;
            app.numValidationImagesEditFieldLabel = uilabel(app.ajustarPanel);
            app.numValidationImagesEditFieldLabel.Text = 'numValidationImages';
            app.numValidationImagesEditField = uieditfield(app.ajustarPanel, 'numeric');
            app.numValidationImagesEditField.Limits = [1 64];
            app.numValidationImagesEditField.Value = 6;
            app.PLAYButton = uibutton(app.ajustarPanel, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Icon = 'play.png';
            app.PLAYButton.Text = {'PLAY'; ''};
            app.RESETButton = uibutton(app.ajustarPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Icon = 'loading.png';
            app.RESETButton.Text = {'RESET'; ''};
            app.TrainingImagesCheckBox = uicheckbox(app.ajustarPanel);
            app.TrainingImagesCheckBox.Text = 'Training Images';
            app.TrainingImagesCheckBox.Value = true;
            app.ValidationImagesCheckBox = uicheckbox(app.ajustarPanel);
            app.ValidationImagesCheckBox.Text = 'Validation Images';
            app.ValidationImagesCheckBox.Value = true;
            app.LayersAnalyzerCheckBox = uicheckbox(app.ajustarPanel);
            app.LayersAnalyzerCheckBox.Text = 'Layers Analyzer';
            app.LayersAnalyzerCheckBox.Value = true;
            app.NetworkAnalyzerCheckBox = uicheckbox(app.ajustarPanel);
            app.NetworkAnalyzerCheckBox.Text = 'Network Analyzer';
            app.NetworkAnalyzerCheckBox.Value = true;
            app.WeightsCheckBox = uicheckbox(app.ajustarPanel);
            app.WeightsCheckBox.Text = 'Weights';
            app.WeightsCheckBox.Value = true;
            app.PanelBotones = uipanel(app.TrainingAlexNetPanel);
            app.RedimensionarButton = uibutton(app.PanelBotones, 'push');
            app.RedimensionarButton.ButtonPushedFcn = createCallbackFcn(app, @RedimensionarButtonPushed, true);
            app.RedimensionarButton.Icon = 'imagenes2.png';
            app.RedimensionarButton.Text = 'Redimensionar';
            app.AjustesButton = uibutton(app.PanelBotones, 'push');
            app.AjustesButton.ButtonPushedFcn = createCallbackFcn(app, @AjustesButtonPushed, true);
            app.AjustesButton.Icon = 'configuraciones.png';
            app.AjustesButton.Text = 'Ajustes';
            app.Atras = uibutton(app.TrainingAlexNetPanel, 'push');
            app.Atras.ButtonPushedFcn = createCallbackFcn(app, @AtrasButtonPushed, true);
            app.Atras.Icon = 'atras.png';
            app.Atras.Text = '';
            app.MAIN_MENU = uipanel(app.TFGUIFigure);
            app.MAIN_MENU.Title = 'MAIN MENU';
            app.MAIN_MENU.Visible = 'off';
            app.TRAININGButton = uibutton(app.MAIN_MENU, 'push');
            app.TRAININGButton.ButtonPushedFcn = createCallbackFcn(app, @TRAININGButtonPushed, true);
            app.TRAININGButton.Icon = 'training.png';
            app.TRAININGButton.Text = 'TRAINING';
            app.CLASSIFICATIONButton_2 = uibutton(app.MAIN_MENU, 'push');
            app.CLASSIFICATIONButton_2.ButtonPushedFcn = createCallbackFcn(app, @CLASSIFICATIONButton_2Pushed, true);
            app.CLASSIFICATIONButton_2.Icon = 'clasification.png';
            app.CLASSIFICATIONButton_2.Text = 'CLASSIFICATION';
            app.atrasButton_2 = uibutton(app.MAIN_MENU, 'push');
            app.atrasButton_2.ButtonPushedFcn = createCallbackFcn(app, @atrasButton_2Pushed, true);
            app.atrasButton_2.Icon = 'atras.png';
            app.atrasButton_2.Text = '';
            app.THINGSPEAKButton = uibutton(app.MAIN_MENU, 'push');
            app.THINGSPEAKButton.ButtonPushedFcn = createCallbackFcn(app, @THINGSPEAKButtonPushed, true);
            app.THINGSPEAKButton.Icon = 'thingSpeak.png';
            app.THINGSPEAKButton.Text = {'THING SPEAK'; ''};
            app.TrainingGoogleNetPanel = uipanel(app.TFGUIFigure);
            app.TrainingGoogleNetPanel.Title = 'TRAINING GOOGLENET';
            app.TrainingGoogleNetPanel.Visible = 'off';
            app.PanelEmpty_2 = uipanel(app.TrainingGoogleNetPanel);
            app.RedimensionarPanel_2 = uipanel(app.PanelEmpty_2);
            app.RedimensionarPanel_2.Visible = 'off';
            app.CARGARIMAGENButton_2 = uibutton(app.RedimensionarPanel_2, 'push');
            app.CARGARIMAGENButton_2.ButtonPushedFcn = createCallbackFcn(app, @CARGARIMAGENButton_2Pushed, true);
            app.CARGARIMAGENButton_2.Text = 'CARGAR IMAGEN';
            app.STARTButton_3 = uibutton(app.RedimensionarPanel_2, 'push');
            app.STARTButton_3.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_3Pushed, true);
            app.STARTButton_3.Visible = 'off';
            app.STARTButton_3.Text = 'START';
            app.Image_2 = uiimage(app.RedimensionarPanel_2);
            app.Image_2.ImageSource = 'interrogacion.jpg';
            app.EXITOLabel_2 = uilabel(app.RedimensionarPanel_2);
            app.EXITOLabel_2.Visible = 'off';
            app.EXITOLabel_2.Text = '¡ÉXITO AL REDIMENSIONAR!';
            app.ajustarPanel_2 = uipanel(app.PanelEmpty_2);
            app.ajustarPanel_2.Visible = 'off';
            app.MiniBatchSizeEditField_2Label = uilabel(app.ajustarPanel_2);
            app.MiniBatchSizeEditField_2Label.Text = 'MiniBatchSize';
            app.MiniBatchSizeEditField_2 = uieditfield(app.ajustarPanel_2, 'numeric');
            app.MiniBatchSizeEditField_2.Limits = [4 32];
            app.MiniBatchSizeEditField_2.Value = 10;
            app.MaxEpochsEditField_2Label = uilabel(app.ajustarPanel_2);
            app.MaxEpochsEditField_2Label.Text = 'MaxEpochs';
            app.MaxEpochsEditField_2 = uieditfield(app.ajustarPanel_2, 'numeric');
            app.MaxEpochsEditField_2.Limits = [1 30];
            app.MaxEpochsEditField_2.Value = 10;
            app.InitialLearnRateEditField_2Label = uilabel(app.ajustarPanel_2);
            app.InitialLearnRateEditField_2Label.Text = 'InitialLearnRate';
            app.InitialLearnRateEditField_2 = uieditfield(app.ajustarPanel_2, 'numeric');
            app.InitialLearnRateEditField_2.Limits = [1e-07 0.01];
            app.InitialLearnRateEditField_2.Value = 0.0001;
            app.ValidationFrequencyEditField_2Label = uilabel(app.ajustarPanel_2);
            app.ValidationFrequencyEditField_2Label.Text = 'ValidationFrequency';
            app.ValidationFrequencyEditField_2 = uieditfield(app.ajustarPanel_2, 'numeric');
            app.ValidationFrequencyEditField_2.Limits = [1 64];
            app.ValidationFrequencyEditField_2.Value = 3;
            app.ValidationPatienceEditField_2Label = uilabel(app.ajustarPanel_2);
            app.ValidationPatienceEditField_2Label.Text = 'ValidationPatience';
            app.ValidationPatienceEditField_2 = uieditfield(app.ajustarPanel_2, 'numeric');
            app.ValidationPatienceEditField_2.Limits = [0 Inf];
            app.ValidationPatienceEditField_2.Editable = 'off';
            app.ValidationPatienceEditField_2.Value = Inf;
            app.numTrainImagesEditField_2Label = uilabel(app.ajustarPanel_2);
            app.numTrainImagesEditField_2Label.Text = 'numTrainImages';
            app.numTrainImagesEditField_2 = uieditfield(app.ajustarPanel_2, 'numeric');
            app.numTrainImagesEditField_2.Limits = [1 64];
            app.numTrainImagesEditField_2.Value = 64;
            app.numValidationImagesEditField_2Label = uilabel(app.ajustarPanel_2);
            app.numValidationImagesEditField_2Label.Text = 'numValidationImages';
            app.numValidationImagesEditField_2 = uieditfield(app.ajustarPanel_2, 'numeric');
            app.numValidationImagesEditField_2.Limits = [1 64];
            app.numValidationImagesEditField_2.Value = 6;
            app.PLAYButton_2 = uibutton(app.ajustarPanel_2, 'push');
            app.PLAYButton_2.ButtonPushedFcn = createCallbackFcn(app, @PLAYButton_2Pushed, true);
            app.PLAYButton_2.Icon = 'play.png';
            app.PLAYButton_2.Text = {'PLAY'; ''};
            app.RESETButton_2 = uibutton(app.ajustarPanel_2, 'push');
            app.RESETButton_2.ButtonPushedFcn = createCallbackFcn(app, @RESETButton_2Pushed, true);
            app.RESETButton_2.Icon = 'loading.png';
            app.RESETButton_2.Text = {'RESET'; ''};
            app.TrainingImagesCheckBox_2 = uicheckbox(app.ajustarPanel_2);
            app.TrainingImagesCheckBox_2.Text = 'Training Images';
            app.TrainingImagesCheckBox_2.Value = true;
            app.ValidationImagesCheckBox_2 = uicheckbox(app.ajustarPanel_2);
            app.ValidationImagesCheckBox_2.Text = 'Validation Images';
            app.ValidationImagesCheckBox_2.Value = true;
            app.NetworkAnalyzerCheckBox_2 = uicheckbox(app.ajustarPanel_2);
            app.NetworkAnalyzerCheckBox_2.Text = 'Network Analyzer';
            app.NetworkAnalyzerCheckBox_2.Value = true;
            app.WeightsCheckBox_2 = uicheckbox(app.ajustarPanel_2);
            app.WeightsCheckBox_2.Text = 'Weights';
            app.WeightsCheckBox_2.Value = true;
            app.PanelBotones_2 = uipanel(app.TrainingGoogleNetPanel);
            app.RedimensionarButton_2 = uibutton(app.PanelBotones_2, 'push');
            app.RedimensionarButton_2.ButtonPushedFcn = createCallbackFcn(app, @RedimensionarButton_2Pushed, true);
            app.RedimensionarButton_2.Icon = 'imagenes2.png';
            app.RedimensionarButton_2.Text = 'Redimensionar';
            app.AjustesButton_2 = uibutton(app.PanelBotones_2, 'push');
            app.AjustesButton_2.ButtonPushedFcn = createCallbackFcn(app, @AjustesButton_2Pushed, true);
            app.AjustesButton_2.Icon = 'configuraciones.png';
            app.AjustesButton_2.Text = 'Ajustes';
            app.Atras_2 = uibutton(app.TrainingGoogleNetPanel, 'push');
            app.Atras_2.ButtonPushedFcn = createCallbackFcn(app, @Atras_2ButtonPushed, true);
            app.Atras_2.Icon = 'atras.png';
            app.Atras_2.Text = '';
            app.thingSpeakPanel = uipanel(app.TFGUIFigure);
            app.thingSpeakPanel.Title = 'THING SPEAK';
            app.thingSpeakPanel.Visible = 'off';
            app.VEHICLESELECTIONButtonGroup = uibuttongroup(app.thingSpeakPanel);
            app.VEHICLESELECTIONButtonGroup.Title = 'VEHICLE SELECTION';
            app.ACCEPTButton = uibutton(app.VEHICLESELECTIONButtonGroup, 'push');
            app.ACCEPTButton.ButtonPushedFcn = createCallbackFcn(app, @ACCEPTButtonPushed, true);
            app.ACCEPTButton.Text = 'ACCEPT';
            app.COCHESButton = uiradiobutton(app.VEHICLESELECTIONButtonGroup);
            app.COCHESButton.Text = {'COCHES'; ''};
            app.COCHESButton.Value = true;
            app.BUSESButton = uiradiobutton(app.VEHICLESELECTIONButtonGroup);
            app.BUSESButton.Text = 'BUSES';
            app.MOTOSButton = uiradiobutton(app.VEHICLESELECTIONButtonGroup);
            app.MOTOSButton.Text = 'MOTOS';
            app.CAMIONESFURGOSButton = uiradiobutton(app.VEHICLESELECTIONButtonGroup);
            app.CAMIONESFURGOSButton.Text = 'CAMIONES-FURGOS';
            app.ALLButton = uibutton(app.VEHICLESELECTIONButtonGroup, 'push');
            app.ALLButton.ButtonPushedFcn = createCallbackFcn(app, @ALLButtonPushed, true);
            app.ALLButton.Text = 'ALL';
            app.CHANNELButton = uibutton(app.thingSpeakPanel, 'push');
            app.CHANNELButton.ButtonPushedFcn = createCallbackFcn(app, @CHANNELButtonPushed, true);
            app.CHANNELButton.Icon = 'thingSpeak.png';
            app.CHANNELButton.Text = 'CHANNEL';
            app.UITable = uitable(app.thingSpeakPanel);
            app.UITable.ColumnName = {'FECHA'; 'COCHES'; 'BUSES'; 'MOTOS'; 'CAMIONES-FURGOS'};
            app.UITable.RowName = {};
            app.Atras_4 = uibutton(app.thingSpeakPanel, 'push');
            app.Atras_4.ButtonPushedFcn = createCallbackFcn(app, @Atras_4ButtonPushed, true);
            app.Atras_4.Icon = 'atras.png';
            app.Atras_4.Text = '';
            app.TWITTER_button = uibutton(app.thingSpeakPanel, 'push');
            app.TWITTER_button.ButtonPushedFcn = createCallbackFcn(app, @TWITTER_buttonButtonPushed, true);
            app.TWITTER_button.Icon = 'vehiculos.png';
            app.TWITTER_button.Text = 'TWITTER';
            app.CHECKTRAFFICButtonGroup = uibuttongroup(app.thingSpeakPanel);
            app.CHECKTRAFFICButtonGroup.Title = 'CHECK TRAFFIC';
            app.SENDTWEETButton = uibutton(app.CHECKTRAFFICButtonGroup, 'push');
            app.SENDTWEETButton.ButtonPushedFcn = createCallbackFcn(app, @SENDTWEETButtonPushed, true);
            app.SENDTWEETButton.Text = 'SEND TWEET';
            app.COCHESButton_2 = uiradiobutton(app.CHECKTRAFFICButtonGroup);
            app.COCHESButton_2.Text = {'COCHES'; ''};
            app.COCHESButton_2.Value = true;
            app.BUSESButton_2 = uiradiobutton(app.CHECKTRAFFICButtonGroup);
            app.BUSESButton_2.Text = 'BUSES';
            app.MOTOSButton_2 = uiradiobutton(app.CHECKTRAFFICButtonGroup);
            app.MOTOSButton_2.Text = 'MOTOS';
            app.CAMIONESFURGOSButton_2 = uiradiobutton(app.CHECKTRAFFICButtonGroup);
            app.CAMIONESFURGOSButton_2.Text = 'CAMIONES-FURGOS';
            app.DATEDatePickerLabel = uilabel(app.CHECKTRAFFICButtonGroup);
            app.DATEDatePickerLabel.Text = 'DATE';
            app.DATEPicker = uidatepicker(app.CHECKTRAFFICButtonGroup);
            app.DATEPicker.Value = datetime([2021 11 11]);
            app.PrincipalPanel = uipanel(app.TFGUIFigure);
            app.PrincipalPanel.Title = 'RECONOCIMIENTO DE VEHÍCULOS';
            app.ImagePrincipal = uiimage(app.PrincipalPanel);
            app.ImagePrincipal.ImageSource = 'conexion.png';
            app.STARTButton = uibutton(app.PrincipalPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = {'START'; ''};
            % Show the figure after all components are created
            app.TFGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TFGUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TFGUIFigure)
        end
    end
end